classdef DeepEdge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        Mn_LoadModel                    matlab.ui.container.Menu
        Mn_LoadVideo                    matlab.ui.container.Menu
        Mn_LoadContours                 matlab.ui.container.Menu
        Mn_LoadTextGrid                 matlab.ui.container.Menu
        Mn_LoadVideoAndContours         matlab.ui.container.Menu
        Mn_LoadVideoAndTextGrid         matlab.ui.container.Menu
        Mn_LoadVideoAndContoursAndTextGrid  matlab.ui.container.Menu
        Mn_LoadAudio                    matlab.ui.container.Menu
        Mn_ReselectTextGridTier         matlab.ui.container.Menu
        Mn_ShowSelectFromFileList       matlab.ui.container.Menu
        Mn_Export                       matlab.ui.container.Menu
        Mn_ExportCurrentFrame           matlab.ui.container.Menu
        Mn_ExpImgRaw                    matlab.ui.container.Menu
        Mn_ExpImgCrop                   matlab.ui.container.Menu
        Mn_Anchors2TSV                  matlab.ui.container.Menu
        Mn_Anchors2TSV_Ori              matlab.ui.container.Menu
        Mn_Anchors2TSV_Crop             matlab.ui.container.Menu
        Mn_ExportMat                    matlab.ui.container.Menu
        Mn_QuickSaveContours            matlab.ui.container.Menu
        Mn_ExportCropPara               matlab.ui.container.Menu
        CommandMenu                     matlab.ui.container.Menu
        Mn_PreviousFrame                matlab.ui.container.Menu
        Mn_NextFrame                    matlab.ui.container.Menu
        Mn_ApplyCurrentFrame            matlab.ui.container.Menu
        Mn_Track                        matlab.ui.container.Menu
        Mn_StopTracking                 matlab.ui.container.Menu
        Mn_DeleteAllAnchorsCurrentFrame  matlab.ui.container.Menu
        Mn_TrackNextFrame               matlab.ui.container.Menu
        Mn_TrackNFrames                 matlab.ui.container.Menu
        Mn_ApplySnake                   matlab.ui.container.Menu
        Mn_DeleteAllAnchorsinAllFrames  matlab.ui.container.Menu
        Mn_FindMPP                      matlab.ui.container.Menu
        AudioMenu                       matlab.ui.container.Menu
        Mn_AddAudioOffset               matlab.ui.container.Menu
        Mn_ViewAll                      matlab.ui.container.Menu
        Mn_ZoomIn                       matlab.ui.container.Menu
        Mn_ZoomOut                      matlab.ui.container.Menu
        Mn_ScrollPageBack               matlab.ui.container.Menu
        Mn_ScrollPageForward            matlab.ui.container.Menu
        Mn_ZoomToAudioCursor            matlab.ui.container.Menu
        Mn_ZoomToSelection              matlab.ui.container.Menu
        Mn_PlaySelectedAudio            matlab.ui.container.Menu
        InfoMenu                        matlab.ui.container.Menu
        Mn_VideoInfo                    matlab.ui.container.Menu
        Mn_ModelInfo                    matlab.ui.container.Menu
        Mn_AboutDeepEdge                matlab.ui.container.Menu
        Ed_CursorLoc                    matlab.ui.control.EditField
        Bn_NextFile                     matlab.ui.control.Button
        Pn_FrameControl                 matlab.ui.container.Panel
        Ed_GoToN                        matlab.ui.control.NumericEditField
        Bn_GoTo                         matlab.ui.control.Button
        Bn_LastFrame                    matlab.ui.control.Button
        Ed_NextN                        matlab.ui.control.NumericEditField
        Bn_NextNFrame                   matlab.ui.control.Button
        Bn_NextFrame                    matlab.ui.control.Button
        Bn_PreviousFrame                matlab.ui.control.Button
        Bn_PreviousNFrame               matlab.ui.control.Button
        Ed_PreviousN                    matlab.ui.control.NumericEditField
        Bn_FirstFrame                   matlab.ui.control.Button
        Bn_PreviousFile                 matlab.ui.control.Button
        Msg1                            matlab.ui.control.TextArea
        Tabs                            matlab.ui.container.TabGroup
        Tab_Config                      matlab.ui.container.Tab
        BnG_EnhanceContrast             matlab.ui.container.ButtonGroup
        Bn_AdaptHistEq                  matlab.ui.control.RadioButton
        Bn_HistEq                       matlab.ui.control.RadioButton
        Bn_Adjust                       matlab.ui.control.RadioButton
        Bn_NoEnhance                    matlab.ui.control.RadioButton
        Txt_tracked_frames              matlab.ui.control.Label
        Ck_Autosave                     matlab.ui.control.CheckBox
        Ed_savingFreq                   matlab.ui.control.NumericEditField
        everyEditFieldLabel             matlab.ui.control.Label
        Pn_SnakeParas                   matlab.ui.container.Panel
        Bn_SnakeDefault                 matlab.ui.control.Button
        Ed_SnakeLambda1                 matlab.ui.control.NumericEditField
        Slid_SnakeLambda1               matlab.ui.control.Slider
        Lambda1Label                    matlab.ui.control.Label
        Ed_SnakeAlpha                   matlab.ui.control.NumericEditField
        Slid_SnakeAlpha                 matlab.ui.control.Slider
        Lambda1Label_2                  matlab.ui.control.Label
        Ed_SnakeBandPenalty             matlab.ui.control.NumericEditField
        BandPenaltyLabel                matlab.ui.control.Label
        Ed_SnakeDelta                   matlab.ui.control.NumericEditField
        DeltaLabel                      matlab.ui.control.Label
        Ed_SnakeSigma                   matlab.ui.control.NumericEditField
        SigmaLabel                      matlab.ui.control.Label
        Sw_ifInvert                     matlab.ui.control.Switch
        CropParaHelpMsg                 matlab.ui.control.EditField
        Ed_CropRect                     matlab.ui.control.EditField
        Bn_Uncrop                       matlab.ui.control.Button
        Bn_CropButton                   matlab.ui.control.StateButton
        AnchorTab                       matlab.ui.container.Tab
        UIAnchorTable                   matlab.ui.control.Table
        Sw_ContourVisible               matlab.ui.control.Switch
        ContourvisibleSwitchLabel       matlab.ui.control.Label
        Bn_DelAnchors                   matlab.ui.control.Button
        Slid_AnchorAlpha                matlab.ui.control.Slider
        OpacityLabel                    matlab.ui.control.Label
        Slid_AnchorSize                 matlab.ui.control.Slider
        AnchorsizeSliderLabel           matlab.ui.control.Label
        Sw_ClickAnchor                  matlab.ui.control.Switch
        ClickanchorsSwitchLabel         matlab.ui.control.Label
        DeepEdgeTab                     matlab.ui.container.Tab
        Bn_TrackFiles                   matlab.ui.control.StateButton
        Ed_MaskSigma                    matlab.ui.control.NumericEditField
        MasksigmaEditFieldLabel         matlab.ui.control.Label
        Ck_UseMaskFromPreviousFrame     matlab.ui.control.CheckBox
        Ed_TrackingDelayMs              matlab.ui.control.NumericEditField
        DelaymsecLabel                  matlab.ui.control.Label
        Bn_StopTrack                    matlab.ui.control.StateButton
        Bn_TrackFrames                  matlab.ui.control.StateButton
        Bn_ApplyCurrentFrame            matlab.ui.control.Button
        DetectMethodGroup               matlab.ui.container.ButtonGroup
        Ck_NNSnake                      matlab.ui.control.RadioButton
        Ck_Snake                        matlab.ui.control.RadioButton
        Ck_NN                           matlab.ui.control.RadioButton
        DiagnosticTab                   matlab.ui.container.Tab
        Ck_ShowDiagOnAudio              matlab.ui.control.CheckBox
        UIBadContourTable               matlab.ui.control.Table
        Pn_DiagnoseFilter               matlab.ui.container.Panel
        Bn_Diagnose                     matlab.ui.control.Button
        Ed_RoughnessFilter              matlab.ui.control.NumericEditField
        Ck_RoughnessFilter              matlab.ui.control.CheckBox
        RoughnessLabel                  matlab.ui.control.Label
        Ed_EnergyQ3Filter               matlab.ui.control.NumericEditField
        Ck_EnergyQ3Filter               matlab.ui.control.CheckBox
        Ed_EnergyMaxFilter              matlab.ui.control.NumericEditField
        Ck_EnergyMaxFilter              matlab.ui.control.CheckBox
        Ed_EnergyMeanFilter             matlab.ui.control.NumericEditField
        Ck_EnergyMeanFilter             matlab.ui.control.CheckBox
        JointEnergyLabel                matlab.ui.control.Label
        Pn_SnakeEngergy                 matlab.ui.container.Panel
        Ed_Roughness                    matlab.ui.control.EditField
        SmoothnessLabel                 matlab.ui.control.Label
        Ed_SnakeEnergyQ3                matlab.ui.control.EditField
        Q3Label                         matlab.ui.control.Label
        Ed_SnakeEnergyMax               matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        Ed_SnakeEnergyMean              matlab.ui.control.EditField
        MeanEditFieldLabel              matlab.ui.control.Label
        JointenergyLabel                matlab.ui.control.Label
        Ed_TotalTime                    matlab.ui.control.NumericEditField
        Ed_Time                         matlab.ui.control.NumericEditField
        TimesLabel                      matlab.ui.control.Label
        Lp_Status                       matlab.ui.control.Lamp
        ReadyLabel                      matlab.ui.control.Label
        Ed_TotalFrames                  matlab.ui.control.EditField
        Ed_Frame                        matlab.ui.control.NumericEditField
        FrameEditFieldLabel             matlab.ui.control.Label
        Ed_FileInd                      matlab.ui.control.EditField
        Ed_CurrentFile                  matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        Ax1                             matlab.ui.control.UIAxes
        Ax0                             matlab.ui.control.UIAxes
    end

%% DeepEdge : 
% Automatic ultrasound tongue contouring 
% combining a deep neural network and an edge detection algorithm
% 
% Copyright (C) 2020 Wei-Rong Chen <chenw@haskins.yale.edu>
% This program is free software under GNU General Public License, version 3.
% This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. 
% See the GNU General Public License for more details.
% wei-rong chen
% Lastest update: wrc 22SEP2022 v2.1
    
    properties (Access = private)

        Version = '2.1'; Update = '22SEP2022';  % Update version
        minMatlabVer = '9.10'; % R2021a
        minMatlabVerName = 'R2021a'; 
        matFileVersion = '-v7'; 
        appPath = fileparts(mfilename('fullpath'));
        vObj; % video object
        crop_rect = [1, 1, 0, 0];% cropping rectangle: [xmin ymin width height]
        ANCHORS = []; % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
        ENERGY = []; XY = []; TIME = []; METHOD = []; MPP = [];
        anchorTableArray = [];
        draw_xy = []; % hand-draw contour
        minDist = 3; % (pixel) min distance in Ax0
        count_modified_frames = 0;
        Ax0_norm_width = 200; % normalized Ax0 width (denominator to determine minDist
        %% Graphic handles:
        handle_rect = []; % handle for the seleted cropping rectangle
        CurrentImgObj = []; %current image object
        handle_xy = []; handle_anchors = []; handle_draw = []; handle_mpp = []; 
        handle_AudioSelection =[]; handle_AudioCursor = [];
        hg_textgrid_labels= []; handle_diagnosis = [];
        %% Visual parameters:
        anchor_size = 45;  anchor_alpha= 0.4; nXYpoints = 100;
        contour_alpha = 0.7; contour_color = 'y'; contour_visible = 'on'; contour_style = ':'; contour_width = 1;
        anchor_color = [1,0,0]; % model-generated anchor color = red
        user_anchor_color = [0, 1, 1]; % user anchor color = rgb('Green');
        anchor_edge_color = 'k';
        ifInvert = false; 
        draw_color = 'c'; draw_alpha = 0.6; draw_width = 1; draw_style = '-'; draw_visible = 'On';
        rect_linewidth = 1; rect_linestyle = ':'; rect_marker = 'o'; rect_color = 'r'; 
        mpp_linewidth = 1; mpp_linestyle = ':'; mpp_marker = '_'; mpp_color = 'r'; 
        status_busy_color = 'r'; status_ready_color = 'g'; status_standby_color = 'k';
        MppLineAlpha = 0.5; 
        diagnosis_marker = '|'; diagnosis_color = 'r'; diagnosis_marker_size = 5;
        diagnosis_visible = 'on'; diagnosis_alpha = 0.7; 
        %% Saving variables:
        frame_list = []; modelname = []; net = []; modelPath = [];
        img = []; % original image of current frame
        height=[]; width=[]; duration=[]; % video height & width & duration
        FrameRate = []; nFrames = [];
        selectedAnchorID = []; mask = []; hg_mask_boundary = [];
        defaultMsg = []; AUDIO=[]; 
        TextGrid =[]; TextGridTier =[]; TextGridFilename = [];
        WhichTextGridTierSelected  = []; 
        PARAMS = []; % Collection of parameters
        minSnakeAnchors = 3;
        AudPLAYER = []; filelist = []; filePath = []; 
        LoadMode = [] % app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
        PreviousClickAnchorValue = 'On';
        CurrentFileNumber = [];
        TrackingDelayMs = 20; 
        flag_ifAnchorModifed = false;
        flag_FrameTrackingOn = false; flag_FileTrackingOn = false;
        snakeDefaultPara=[]; snakePara = [];
        audioOffset = 0; minAudioDisplayDur = 0.010; AudioCursorTime = [];
        minAudioPlayDur = 0.045;
        minAudioSelectionDenominator = 200; minAudioSelectDur = [];
        diagnosticMatrix = []; BadContourFrameIndex = [];
        %% Flags:
        active = false; % set active to true when video is loaded.
        flag_GetMppOn = false;  
        flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
        flag_SelectRectMouseDown = false; % flag to indicate mouse button down for SelectCropRect
        flag_mouseClicked = false; flag_LeftClickDown = false; flag_RightClickDown = false; 
        flag_busy = false;
        Ax1ClickLog = [];
    end
    methods (Access = private)
        %%
        function initialize(app)
            % Run at App Startup & Loading a video file:
            %
            % Output results:
            app.ANCHORS = []; % ANCHORS{iFrame} : n x 3 {x, y, isUserInput} 
            app.anchorTableArray = NaN(1,2); 
            app.UIAnchorTable.Data = app.anchorTableArray;
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.XY = []; app.ENERGY = []; app.TIME = []; app.METHOD = [];
            app.flag_ifAnchorModifed = false;  % true if there is unsaved changes in the anchors. 
            app.diagnosticMatrix = []; app.BadContourFrameIndex = [];
            app.count_modified_frames = 0;
            % VIDEO:
            app.vObj = []; app.Ed_CurrentFile.Value = '';
            % AUDIO:
            app.AUDIO = []; app.PARAMS.AudioResampleRate = 10000; 
            app.PARAMS.AudioAllColor = [0.3, 0.3, 0.3]; % Audio display color: rgb('gray')
            app.PARAMS.AudioCursorColor = [0.54, 0, 0]; % rgb('darkred')
            app.PARAMS.AudioCursorAlpha = 0.6; app.PARAMS.AudioCursorWidth = 1;
            app.PARAMS.AudioSelectColor = [1.0000    0.4102    0.7031]; % rgb('HotPink')
            app.PARAMS.AudioSelectAlpha = 0.5;
            app.PARAMS.AudioCursorMinWidth = 0.005; % (sec) Minimum display width for audio cursor.
            app.minAudioSelectDur = []; % (sec);
            app.TextGridTier = []; % TextGridTier.segs: timestamps;  TextGridTier.labs: labels
            app.TextGrid = []; app.TextGridFilename = [];
            app.PARAMS.TextGridLineColor = [0, 0.39, 0]; % rgb('darkgreen')
            app.PARAMS.TextGridFontSize = 9;
            app.PARAMS.TextGridLineAlpha = 0.6;
            app.PARAMS.TextGridLabelColor = [0, 0, 0.5];
            % Deactivate: 
            app.active = false; % set active to true when video is loaded.
            app.flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
            app.flag_SelectRectMouseDown = false; % flag to indicate mouse button down for SelectCropRect
            rect = app.crop_rect; 
            app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
%             app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
            % Ax0: Main image axes
            app.Ax0.Visible = 'off'; cla(app.Ax0, 'reset'); 
            app.Ax0.Interactions = []; app.Ax0.Box = 'on'; app.Ax0.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Ax0)
            disableDefaultInteractivity(app.Ax1)
            %% UserData for frame changing buttons specify 'how many frame to move forward/backward'
            app.Bn_NextFrame.UserData = 1; app.Bn_PreviousFrame.UserData = -1; 
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
            app.Bn_FirstFrame.UserData = -inf; app.Bn_LastFrame.UserData = inf; 
            app.Ed_GoToN.Value = 1; 
            app.Mn_NextFrame.UserData = 1; app.Mn_PreviousFrame.UserData = -1;
            %% UserData for loading Previous/Next file:
            app.Bn_NextFile.UserData = 1; app.Bn_PreviousFile.UserData = -1;
            %% UserData for Menu Load video(s) LoadMode:
            app.Mn_LoadVideo.UserData = 'Video';
            app.Mn_LoadVideoAndContours.UserData = 'Video&Contour';
            app.Mn_LoadVideoAndTextGrid.UserData = 'Video&TextGrid';
            app.Mn_LoadVideoAndContoursAndTextGrid.UserData = 'Video&Contour&TextGrid';
            %% UserData for audio view:
            app.Mn_ZoomIn.UserData = 0.5;
            app.Mn_ZoomOut.UserData = 2;
            app.Mn_ScrollPageBack.UserData = - 0.5; 
            app.Mn_ScrollPageForward.UserData = 0.5;
            %% UserData for contour detect method:
             app.DetectMethodGroup.UserData = app.DetectMethodGroup.SelectedObject.Text;
             %% UserData for N-next frames to tracking
             app.Mn_Track.UserData = inf; app.Bn_TrackFrames.UserData = inf;
             app.Mn_TrackNextFrame.UserData = 1;
             app.Mn_TrackNFrames.UserData =  app.Ed_NextN.Value;
             app.Mn_TrackNFrames.Text = sprintf('Track next %d frames', app.Ed_NextN.Value);
            %% UserData for silent mode for Export/Quick Save Contours to MAT file
            app.Mn_ExportMat.UserData.Silent = false;
            app.Mn_QuickSaveContours.UserData.Silent = true;
             %%
            app.Sw_ClickAnchor.Value = 'On';
            app.img = []; % original image of current frame
            app.anchor_size = 45;  app.anchor_alpha= 0.4; app.nXYpoints = 100;
            app.Slid_AnchorSize.Value = 25; app.Slid_AnchorAlpha.Value=70;
            app.ifInvert = false;
            app.selectedAnchorID = []; app.mask = [];  app.AudPLAYER = [];
            app.handle_draw = []; app.draw_xy = []; 
            %% 
            app.Mn_ExpImgRaw.UserData.Crop=false; app.Mn_ExpImgCrop.UserData.Crop=true;
            app.Mn_Anchors2TSV_Ori.UserData.Crop = false; app.Mn_Anchors2TSV_Crop.UserData.Crop = true;
            % Graphic handles: 
            app.CurrentImgObj = []; %current image object
            app.handle_rect = []; % handle for the seleted cropping rectangle 
            app.hg_mask_boundary=[];% handle group of two animatedlines
            app.hg_textgrid_labels = []; % handle group of TextGrid labels
            app.handle_AudioSelection = []; app.handle_AudioCursor = [];
            app.handle_diagnosis;
            app.defaultMsg = '';
            cla(app.Ax1); % Clear axis
            %% Reset diagnosis:
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
                app.Ed_Roughness.Value ='';   
                app.UIBadContourTable.Data = [];
            %% Set tooltips:
            Ed_savingFreqValueChanged(app);
            %%
            SetStatus(app, 'standby');
        end % function initialize
        %%
        function SetStatus(app, status)
            isBusy = false;
            if strcmpi(status, 'busy')
                app.Lp_Status.Color = app.status_busy_color; 
                isBusy = true;
            elseif strcmpi(status, 'ready')
                app.Lp_Status.Color = app.status_ready_color; 
                isBusy = false;
            elseif strcmpi(status, 'standby')
                app.Lp_Status.Color = app.status_standby_color; 
                isBusy = false;
            end
            if isBusy
                app.flag_busy = true;
            else 
                 app.flag_busy = false;
            end
        end %SetStatus
        function [filelist, selectionSuccess, vpath] = SelectVideoFileUI(app)
            % If selected 1 video file, the full path will be returned as
            % FName. If more than 1 video files are selected, FName is the
            % first video file in the selected filelist.  

            % Display uigetfile dialog
            %% Deactivate: 
%             app.active = false; 
            filterspec = {'*.avi;*.mp4;*.wmv;*.mov','All Video Files'};
            [filelist, vpath] = uigetfile(filterspec, 'multiselect', 'on');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(filelist,0),  selectionSuccess = false;  filelist=[]; vpath = []; return; end 
            selectionSuccess = true; app.WhichTextGridTierSelected = []; 
           
            if ~iscell(filelist) % only 1 file selected
                filelist = {filelist};
            end

        end
        %% 
        function success = LoadOneFileFromFilelist(app, whichFile)
            % Load "whichFile" in the filelist. 
            %  "whichFile" can be either numeric or a string of filename.
%        app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
            fl = app.filelist; vPath = app.filePath;
            if isnumeric(whichFile)
                ind = whichFile;
            elseif ischar(whichFile)
                ind = find(ismember(fl, whichFile));
            else
                return
            end
            if ind < 0 || ind > numel(fl), return; end  
            app.CurrentFileNumber = ind; fn = fl{ind}; 
            app.Ed_FileInd.Value = sprintf('%d/%d', ind, numel(fl));
            FName = fullfile(vPath, fn);     
            %  Load video:
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); 
            app.Ed_CurrentFile.Value = fn;
            success = LoadVideoActivateApp(app, FName);  
            if ~success, return; end
            dispFrame(app, 1); 
            close(UIprogressDialog_handle)
            [p, f, e] = fileparts(FName);  matFName = fullfile(p, [f '.mat']);    tgFName = fullfile(p, [f '.TextGrid']); 
            
            switch app.LoadMode
                case 'Video&Contour'
                     % Load contours:
                     if exist(matFName, 'file'), LoadContourStructFromMatFile(app, matFName); end  
                case 'Video&TextGrid'
                    % Load TextGrid:
                    if exist(tgFName, 'file') && ~isempty(app.AUDIO)
                        tgSuccess = loadTextGrid(app, tgFName); 
                        if tgSuccess, drawTextGridIntervals(app); end
                    end
                case 'Video&Contour&TextGrid'
                     % Load contours:
                     if exist(matFName, 'file'), LoadContourStructFromMatFile(app, matFName); end  
                    % Load TextGrid:
                    if exist(tgFName, 'file') && ~isempty(app.AUDIO)
                        tgSuccess = loadTextGrid(app, tgFName); 
                        if tgSuccess, drawTextGridIntervals(app); end
                    end
            end % switch app.LoadMode
%             SetStatus(app, 'ready');
        end
        %%
        function success = LoadVideoActivateApp(app,videoFName)
           %UIFigure.Pointer is supported after R2020a (ver9.8)
            set(app.UIFigure, 'pointer','watch');
            cla(app.Ax1); % Clear axis
            success = Read1VideoFile(app, videoFName);
            if ~success, return; end
            successfullyReadAudio = Read1AudioFile(app, videoFName); %#ok<NASGU> 
            app.active = true; % Activate the App
            InitializeVarsForOneFile(app)
        end %LoadVideoActivateApp(app,FName)
        %% 
        function InitializeVarsForOneFile(app)
            % Reset/initialize variables for one video file.
            axis(app.Ax0, 'image');
            app.Ed_CurrentFile.Value = app.vObj.Name;
            app.nFrames = app.vObj.NumFrames; app.Ed_TotalFrames.Value = ['/ ' num2str(app.nFrames)]; 
            app.Ed_TotalTime.Value = app.vObj.Duration; 
            app.ANCHORS = cell(app.nFrames,1); app.XY = cell(app.nFrames,1); 
            app.ENERGY = cell(app.nFrames,1); app.TIME = cell(app.nFrames,1); app.METHOD = cell(app.nFrames,1); 
            app.frame_list = 1:app.nFrames; 
            %  IF:
            %    1. loading video the first time
            %    2. the newly loaded video has different height or width from the previous video.
            %  THEN: 
            %     reset cropping parameters and XLim, YLim
            %  ELSE:
            %     retain the previous cropping paramters and XLim, YLim
            if isempty(app.crop_rect) || isempty(app.height) || isempty(app.width) || app.height ~= app.vObj.Height || app.width ~= app.vObj.Width
                 app.crop_rect = [1, 1, app.vObj.Width, app.vObj.Height]; 
            elseif app.crop_rect(3) ==0 || app.crop_rect(4) == 0
                app.crop_rect = [app.crop_rect(1:2), app.vObj.Width, app.vObj.Height]; 
            end
            rect = app.crop_rect;
            app.Ax0.XLim = [rect(1),rect(1)+rect(3)]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)]; 
            UpdateMinDist(app);
            updateStoredCropRect(app, app.crop_rect);
            app.height = app.vObj.Height; app.width = app.vObj.Width; app.duration = app.vObj.Duration;
        end
        %%
        function UpdateMinDist(app)
            w = abs(app.Ax0.XLim(2) - app.Ax0.XLim(1)); h = abs(app.Ax0.YLim(2) - app.Ax0.YLim(1));
            minD = (max([w,h])) / app.Ax0_norm_width;
            if minD < 1, minD = 1; end
            app.minDist = minD;
        end % UpdateMinDist
        %%
        function success = Read1VideoFile(app, videoFName)
            try 
                app.vObj = VideoReader(videoFName); 
                app.Msg1.Value = '';  
                success = true;
                set(app.UIFigure, 'pointer','arrow'); 
            catch 
                app.Msg1.Value = 'Unsupported video file!';  success = false;
                set(app.UIFigure, 'pointer','arrow'); 
            end
        end % success = Read1VideoFile(app, videoFName)
        %%
        function success = Read1AudioFile(app, FName)
            % AudOffset: adding offset to audio 
            % if AudOffset > 0, add zeros at the begining of the audio. 
            % if AudOffset < 0, chop off audio at the begining. 
            AudOffset = app.audioOffset; 
            try
                [s, sr, Dur] = app.ImportAudio_simple(FName, app.PARAMS.AudioResampleRate);
                
                if AudOffset > 0
                    delayN = floor(AudOffset * sr) +1; Dur = Dur + AudOffset;
                    padding = zeros(delayN , 1);  
                    s = [padding; s];
                    app.Msg1.Value = ['Audio shifted to the right by ' sprintf('%0.1f', AudOffset*1000) ' ms'];
                elseif AudOffset < 0
                    delayN = floor(AudOffset * sr) +1; Dur = Dur + AudOffset;
                    s = s(delayN:end);
                    app.Msg1.Value = ['Audio chopped off by ' sprintf('%0.1f', AudOffset*1000) ' ms'];
                else 
                    app.Msg1.Value = app.defaultMsg;
                end
                x = linspace(0, Dur, length(s)); 
                h=plot(app.Ax1, x, s, 'color', app.PARAMS.AudioAllColor,'HitTest','off'); %#ok<NASGU> 
                set(app.Ax1, 'XLim', [0,Dur]);  
                app.Ax1.YLim = [-1,1];
                app.AUDIO.s = s; app.AUDIO.sr = sr; app.AUDIO.Dur = Dur;
                app.AUDIO.FileName = FName;
                plotUpdateAudioCursor(app, 0);  success = true;
                app.PARAMS.AudioCursorMinWidth = Dur/1000;      
                app.minAudioSelectDur  = Dur / app.minAudioSelectionDenominator; 
            catch 
                app.AUDIO = [];  success = false;
            end
        end 
        %%
        function dispFrame(app, iFrame)
            if ~app.active, return;end
            if iFrame < 1 || iFrame > app.nFrames,return;end
            currentFrame = app.Ed_Frame.Value;  currentTime = app.Ed_Time.Value;
            try 
                im= read(app.vObj,iFrame);     
            catch
                app.Ed_Frame.Value = currentFrame; app.Ed_Time.Value= currentTime; return;
            end
            %% Go to frame:
            app.Ed_Frame.Value = iFrame;    frameTime = (iFrame-1)/app.vObj.FrameRate;
            app.Ed_Time.Value = frameTime;%
            app.TIME{iFrame} = frameTime;

            if size(im, 3) > 1, im = rgb2gray(im);   end
            colormap(app.Ax0, 'gray');
            enhanceMethod = app.BnG_EnhanceContrast.SelectedObject.Text;
            switch enhanceMethod
                case 'None'
%                     im = im;
                case 'Adjust'
                    im = imadjust(im);
                case 'HistEq'
                    im = histeq(im);
                case 'AdaptHistEq'
                    im = adapthisteq(im);
            end     

            app.img = im;
            
            if app.ifInvert, im = imcomplement(im); end
            if isempty(app.CurrentImgObj)
                app.CurrentImgObj = imagesc(app.Ax0, im); 
                colormap(app.Ax0, 'gray');
                app.Ax0.Toolbar.Visible = 'off'; % turn off again because 'imagesc' turns on Ax0.Toolbar
                rect = app.crop_rect;
                app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1]; 
            else
                app.CurrentImgObj.CData = im; 
            end

            maskSigmaP = app.Ed_MaskSigma.Value /100;
            sigma = mean(app.crop_rect(3:4))*maskSigmaP;
            hold(app.Ax0,'on');
            if app.Ck_UseMaskFromPreviousFrame.Value
                if iFrame > 1 && ~isempty(app.ANCHORS{iFrame-1})
                    anchor_previous_frame = app.sortAnchors(app.ANCHORS{iFrame-1}); 
                    [~,app.mask] = app.GenMaskFromAnchors(anchor_previous_frame(:,1:2), ...
                        sigma, app.width, app.height); 
                else
                    app.mask = ones(app.height, app.width);
                end
            else
                app.mask = ones(app.height, app.width); 
            end
            if isempty(app.hg_mask_boundary) || ~isvalid(app.hg_mask_boundary)
                app.hg_mask_boundary = app.DisplayMaskBoundary(app.Ax0, app.mask);
            else
                app.hg_mask_boundary = app.UpdateMaskBoundary(app.mask,app.hg_mask_boundary);
            end
             hold(app.Ax0,'off');
        end % dispFrame(app, frame)
        %%
        function app = plotUpdateAudioCursor(app, x1, x2)
%             if ~app.active, return; end
            % x1 : first audio time-stamp at the mouse click
            % x2 : last point of audio selection
            if nargin < 3, x2 = x1; end
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);
            d = abs(x2 - x1); 
            if d < app.minAudioDisplayDur, d = currentDur/1000; end
            left = min([x1, x2]); right = left + d;
            app.AudioCursorTime = left;
            % xy: [top-left, bottom-left, bottom-right, top-right];
            top = 1; bottom = -1;
            x = [left, right, right, left];
            y = [top, top, bottom, bottom];
            c = app.PARAMS.AudioSelectColor; alpha = app.PARAMS.AudioSelectAlpha;
            hold(app.Ax1,'on');
            if isempty(app.handle_AudioSelection) || ~isgraphics(app.handle_AudioSelection)
                app.handle_AudioSelection = patch(app.Ax1, x, y, c, ...
                        'EdgeColor', 'none', 'FaceColor',  c, 'FaceAlpha', alpha);
            else
                app.handle_AudioSelection.XData = x; 
            end
            c = app.PARAMS.AudioCursorColor; alpha = app.PARAMS.AudioCursorAlpha;
            lw = app.PARAMS.AudioCursorWidth; 
            if isempty(app.handle_AudioCursor) || ~isgraphics(app.handle_AudioCursor)
                app.handle_AudioCursor = plot(app.Ax1, [left, left], [top, bottom], ...
                        'color',  c, 'LineWidth', lw);
                app.handle_AudioCursor.Color(4) = alpha;
            else
                app.handle_AudioCursor.XData = [left, left];  
            end
            hold(app.Ax1,'off');
        end
        %%
        function app = updateStoredCropRect(app, rect)
            app.crop_rect = rect; app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
        end %function app = updateStoredCropRect(app, rect)
        %%
        function app = updatePlotAnchors(app, anchors, method)
            if ~app.active, return; end
            frame = app.Ed_Frame.Value; 
            %% Update stored anchors and contours
            if nargin > 2
                if size(anchors,1) > 1
                     xy = app.interpLine(anchors, app.nXYpoints,'makima');
                elseif  size(anchors,1) == 1
                    xy = anchors(:,1:2);
                else
                    xy = [];
                end
                 app.ANCHORS{frame} = anchors; app.XY{frame} = xy;                
            end 
            if nargin > 2, app.METHOD{frame} = method; end

%             app.Msg1.Value = app.defaultMsg; 
            if isempty(app.ANCHORS{frame})
                if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
                if isgraphics(app.handle_xy), delete(app.handle_xy);end
                %% Update anchor table:
                app.anchorTableArray = NaN(1,2);
                app.UIAnchorTable.Data = app.anchorTableArray;
                %% Update diagnostic panel:
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
                app.Ed_Roughness.Value ='';
                return;
            end
            anchors = app.ANCHORS{frame}; xy = app.XY{frame};
            x = anchors(:,1); y = anchors(:,2); isUser = anchors(:,3); %n = size(anchors,1);
            sz = app.anchor_size; 
            ancColor =  isUser*app.user_anchor_color + ~isUser*app.anchor_color;
            hold(app.Ax0,'on');
            if isempty(app.handle_anchors) || ~isgraphics(app.handle_anchors)
               % First time plotting anchor points:
                app.handle_anchors = scatter(app.Ax0,x,y, sz, ancColor, ...
                    'filled', 'o', 'MarkerFaceAlpha', app.anchor_alpha,'Visible', 'on', ...
                    'MarkerEdgeColor',app.anchor_edge_color, 'MarkerEdgeAlpha', app.anchor_alpha);
            else
% %               % Update existing plotted anchors:
                app.handle_anchors.XData = x; app.handle_anchors.YData = y; 
                app.handle_anchors.SizeData = app.anchor_size;
                app.handle_anchors.CData = ancColor;
                app.handle_anchors.MarkerFaceAlpha = app.anchor_alpha;
                app.handle_anchors.MarkerEdgeAlpha = app.anchor_alpha;
            end
            if isempty(app.handle_xy) || ~isgraphics(app.handle_xy)
                % First time plotting XY connecting line:
%                 contour_alpha = 0.7; contour_color = 'y'; contour_visible = 'on'; contour_style = ':'; contour_width = 1;
                app.handle_xy = plot(app.Ax0, xy(:,1), xy(:,2), 'color', app.contour_color, ...
                    'visible', app.contour_visible, 'LineStyle', app.contour_style, 'LineWidth', app.contour_width);
                app.handle_xy.Color(4) = app.contour_alpha;
            else
% %         % Update existing XY connecting line:
                app.handle_xy.Visible = app.contour_visible;
                app.handle_xy.XData = xy(:,1); app.handle_xy.YData = xy(:,2);
                app.handle_xy.Color = app.contour_color; 
                app.handle_xy.LineStyle = app.contour_style; 
                app.handle_xy.Color(4) = app.contour_alpha;  
            end
            drawnow 
            hold(app.Ax0,'off');
            %% Update anchor table:
            x = round(x); y = round(y);
            app.anchorTableArray = [x,y];
            app.UIAnchorTable.Data = app.anchorTableArray;
            %% Update Snake energy shown in the panel:
            energy = app.ENERGY{frame};
            energyDispFormat = '%0.3g';
            if ~isempty(energy) && isnumeric(energy)
                meanEnergy = mean(energy, 'omitnan');
                maxEnergy = max(energy); 
                q3 = quantile(energy, 0.75);
                app.Ed_SnakeEnergyMean.Value = sprintf(energyDispFormat , meanEnergy);
                app.Ed_SnakeEnergyMax.Value = sprintf(energyDispFormat , maxEnergy);
                app.Ed_SnakeEnergyQ3.Value = sprintf(energyDispFormat , q3);
            else
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
            end
            if ~isempty(anchors) && size(anchors,1) > 3
                roughness = app.CalcSnakeRoughness(anchors(:,1:2));
                meanRoughness = mean(roughness, 'omitnan');
                app.Ed_Roughness.Value = sprintf(energyDispFormat, meanRoughness);
            else
                app.Ed_Roughness.Value ='';
            end
        end %function app=updatePlotAnchors(app)
        %%
        function app = do_uncrop(app)
            app.Bn_CropButton.Value = 0; app.flag_SelectCropRect = false;
            app.flag_SelectRectMouseDown = false;
            app.Msg1.Value = app.defaultMsg; 

            app = updateStoredCropRect(app, [1,1,app.width,app.height]);
            app.Ax0.XLim = [1,app.width]; app.Ax0.YLim = [1,app.height]; 
            UpdateMinDist(app);
            set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
        end %function app=do_uncrop(app)
        %%
        function app = do_crop(app)
            if isempty(app.handle_rect), return;end
            rect = app.get_rect_from_plot_handle(app.handle_rect);
            app = updateStoredCropRect(app, rect);
            delete(app.handle_rect); app.handle_rect=[];
            app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1]; 
            UpdateMinDist(app);
        end %function app=do_crop(app)
        function do_mpp(app)
            % Calculate & update/store MPP
            if isempty(app.handle_mpp), return;end
            x = app.handle_mpp.XData; y = app.handle_mpp.YData; 
            x1 = x(1); x2 = x(end); y1 = y(1); y2 = y(end); 
            d = app.udist([x1, y1], [x2, y2]); mpp = 10/d; 
            app.MPP = mpp;  
            app.Msg1.Value = sprintf('Millimeter/Pixel (MPP) = %0.4g', mpp);
            pause(1);
            if isgraphics(app.handle_mpp), delete(app.handle_mpp); end
            app.handle_mpp=[];
        end %do_mpp(app)      
        %%
        function LoadContourStructUI(app)
            [f, p] = uigetfile('*.mat','Select contour file ...');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(f,0),  return; end 
            matFName = fullfile(p, f);              
            LoadContourStructFromMatFile(app, matFName);         
        end  %function LoadContourStructUI(app)
        %%
        function LoadContourStructFromMatFile(app, matFName)
            try
                m = app.load1var(matFName);
            catch
                app.Msg1.Value = 'Invalid contour format.'; return         
            end
             UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading contours MAT file','Indeterminate','on');
            % Check if it is correct struct format:
            if ~isstruct(m) || ~app.hasfield(m,'XY') || ~app.hasfield(m,'ANCHORS') || ~app.hasfield(m,'FRAME') || ~app.hasfield(m,'TIME') || numel(m) > app.nFrames
%                 uialert(app.UIFigure,'Invalid contour format.','Read contour error'); 
                app.Msg1.Value = 'Invalid contour format.'; return
            end
            if ~all(cellfun(@isempty, app.ANCHORS))
                selection = uiconfirm(app.UIFigure, 'Erase all & then load, or Append', 'How to deal with current contours','Options',{'Erase all & Load', 'Append', 'Cancel'}, 'DefaultOption',1,'CancelOption',3);  
                 if strcmpi(selection, 'Erase all & Load'), app= EraseContoursAllFrames(app); end
                if strcmpi(selection, 'Cancel'), return;  end
            end
           %%
            % Import contours frame by frame
            for i = 1:numel(m)
                k = m(i).FRAME; % k=frame number
                anchors = m(i).ANCHORS; 
                anchors(:,3) = 0; % 3rd column is 'IsUser' input.
                app.ANCHORS{k} =  anchors;
                app.XY{k} = m(i).XY; 
                if app.hasfield(m,'TRKRES') && ~isempty(m(i).TRKRES) 
                    if app.hasfield(m(i).TRKRES,'ENERGY') && ~isempty(m(i).TRKRES.ENERGY)
                        app.ENERGY{k} =m(i).TRKRES.ENERGY;
                     end
                    if app.hasfield(m(i).TRKRES,'METHOD') && ~isempty(m(i).TRKRES.METHOD)
                        app.METHOD{k} =m(i).TRKRES.METHOD;
                    end
                end
            end 
            updatePlotAnchors(app);    
            close(UIprogressDialog_handle);
        end %function LoadContourStructFromMatFile(app)
        %%
        function app= EraseContoursAllFrames(app)
            app.ANCHORS = cell(app.nFrames,1); app.XY = cell(app.nFrames,1);  
            app.anchorTableArray = NaN(1,2);
            app.UIAnchorTable.Data = app.anchorTableArray;
            app.ENERGY = cell(app.nFrames,1); app.TIME = cell(app.nFrames,1);  app.METHOD = cell(app.nFrames,1); 
            updatePlotAnchors(app);  
        end %function app= EraseContoursAllFrames(app)
        %%
        function updateAx0Ed_CursorLocValue(app)
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %current x,y positions in Ax0.
            app.Ed_CursorLoc.Value = sprintf('%0.0f, %0.0f', x, y);
        end %updateAx0Ed_CursorLocValue(app)
        function updateAx1Ed_CursorLocValue(app)
            x = app.Ax1.CurrentPoint(1,1);  y = app.Ax1.CurrentPoint(1,2); %current x,y positions in Ax1.
             app.Ed_CursorLoc.Value = sprintf('%0.3f', x);
        end %updateAx0Ed_CursorLocValue(app)
        %%
        function [x,y, t_audio] = update_axes_cursor_loc(app)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1.Position; % Axis for AudioAll
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %current x,y positions in Ax0.
            t_audio = [];
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                app.Ed_CursorLoc.Value = sprintf('%0.0f, %0.0f', x, y);
            elseif app.ifWithinPos(fig_pt, pos_Ax1) && ~isempty(app.AUDIO)
                % If the cursor is within Ax1_AudioAll, update the
                % CursorLoc value to audio time.
                t_audio = app.Ax1.CurrentPoint(1,1); % Cursor current time in audio
                app.Ed_CursorLoc.Value = sprintf('%0.3f', t_audio);
            else
                app.Ed_CursorLoc.Value = '';
            end
        end %function [x,y] = update_axes_cursor_loc(app)
        %%
        function [x,y] = Ax0_cursor_loc(app)
            temp = app.Ax0.CurrentPoint; ax_pt = [temp(1,1) temp(1,2)]; % ax_pt : current x,y positions in Ax0.
            x = round(ax_pt(1)); y = round(ax_pt(2));
        end %function [x,y] = Ax0_cursor_loc(app)  
        %%
        function [im_cropped, im_masked] = framePreprocess(app, im)
            % Preprocess frame image: 
            %  1. Cropping; 2. Masking
            h0 = size(im,1); w0 = size(im,2);
            if isempty(app.mask), app.mask = ones(h0, w0); end
            % if color, convert im to gray scale:
            if size(im, 3) > 1, im = rgb2gray(im); end
            im = im2double(im);   
            mask1 = imgaussfilt( im2double(app.mask),10);  mask1 =  mask1 ./ max(mask1(:));
            mask1_cropped = imcrop(mask1, app.crop_rect);
            im_cropped = imcrop(im, app.crop_rect); 
            im_masked = im2uint8(im_cropped.*mask1_cropped);
            im_cropped = im2uint8(im_cropped);
        end %[im_cropped, im_masked] = framePreprocess(app, im)
        %% 
        function [anchors, snakeEnergy, xy] = detectOneFrame(app, im, method, anchors)
            % 'anchors' : n x 3 {x, y, isUserInput}  % the x-y coordinates in the stored "anchors" are with respect to the original (uncropped) image. 
            % crop_rect: % cropping rectangle: [xmin ymin width height]
            %%   SNAKE parameters: 
            sigma= app.snakePara.Sigma; 
            Delta=app.snakePara.Delta;  
            BandPenalty = app.snakePara.BandPenalty;  
            Alpha = app.snakePara.Alpha; Lambda1 = app.snakePara.Lambda1; 
            UseBand =1; ROI = [];
            snakeEnergy = NaN;
            % alpha : internal engergy
            % beta = 1 - alpha; external engergy
            % TotalEnergy: E = alpha * Eint + beta * Eext
            %  InternalEnergy: Eint = lambda1 * curvature + lambda2 * stiffness
            %  ExternalEngergy: Eext = Egrad + Eband
            %%
            [im_cropped, im_masked] = framePreprocess(app, im);

            nPt = 20; % how many points for interpolation
            cropPt = app.crop_rect(1:2); % Upper-leftmost point (xmin, ymin) in cropping parameters
            % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                snakeEnergy=[]; xy = []; anchors = []; return;
            end
            switch method
                case 'NeuralNet'
                    %% NN prediction
                    [nnXY, tMask, probMask,  nnXYlength, anchors] = predUSTC(app.net, im_cropped, nPt);
                    if isempty(anchors)
                        snakeEnergy=[];xy=[]; anchors = []; 
                        app.Msg1.Value = 'No contour detected';
                        return; 
                    end
                    anchors = [anchors  + cropPt - [1,1], zeros(nPt,1)];
                case 'NN+SNAKE'
                    %% NN predict + SNAKE
                    [nnXY, tMask, probMask,  nnXYlength, anchors] = predUSTC(app.net, im_cropped, nPt);
                    if isempty(anchors) || size(anchors,1) < app.minSnakeAnchors
                        snakeEnergy=[]; xy = []; anchors = []; 
                        app.Msg1.Value = 'No contour detected';
                        return; 
                    end
                    [snakeXY, snakeEnergy]=slurp_snake(im_masked , anchors, [], nPt, sigma, Delta, BandPenalty, Alpha, Lambda1, ROI, [], UseBand);
                    anchors = [snakeXY + cropPt - [1,1], zeros(nPt,1)];
                case 'SNAKE'
                    %% SNAKE only; needs existing anchors
                    if isempty(anchors) || size(anchors,1) < app.minSnakeAnchors || size(anchors, 2) < 2
                        snakeEnergy=[]; xy=[]; anchors = []; 
                        app.Msg1.Value = sprintf('SNAKE error! SNAKE requires at least %d anchors.', app.minSnakeAnchors);
                        return; 
                    end
                    anchors = app.sortAnchors(anchors); 
                    anchors = anchors(:,1:2) - cropPt + [1,1];
                     [snakeXY, snakeEnergy]=slurp_snake(im_masked, anchors, [], nPt, sigma, Delta, BandPenalty, Alpha, Lambda1, ROI, [], UseBand);
                    anchors = [snakeXY + cropPt - [1,1], zeros(nPt,1)];      
                otherwise
                    snakeEnergy=[]; xy = []; anchors = []; return;
            end % switch method
        
            if size(anchors,1) > 2
                xy = app.interpLine(anchors(:,1:2), app.nXYpoints, 'makima');
            else
                xy = anchors;
            end
            % Round up: 
            xy = round(xy, 2); anchors = round(anchors, 2);
        end % function [anchors, snakeEnergy, xy] = detectOneFrame
        %
        function loadModel(app, FName)
            % function pragma to load toolboxes:
            %#function nnet.cnn.layer.PixelClassificationLayer
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading model','Indeterminate','on');
            mat = app.load1var(FName,'net');
            close(UIprogressDialog_handle)
            if ~isa(mat, 'DAGNetwork')
                uialert(app.UIFigure,'Incorrect model','Error loading model'); 
                return            
            end
            [p,f,~]=fileparts(FName); app.net = mat; app.modelname = f; app.modelPath = p; 
        end
        %%
        function success = loadTextGrid(app, FName)
            delay = app.audioOffset;
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading TextGrid file','Indeterminate','on');
            SetStatus(app, 'busy');
            try
                [TG, TierNames] = app.ReadTextGrid(FName); 
                if delay ~= 0
                    for i =1:numel(TG)
                        TG(i).segs = TG(i).segs +delay; 
                    end
                end
                success = true; 
                app.TextGrid = TG; app.TextGridFilename = FName;
            catch 
                app.Msg1.Value = 'Invalid TextGrid'; 
                success = false; SetStatus(app, 'ready');
                close(UIprogressDialog_handle);
                return
            end
            
            if numel(app.filelist) > 1 && ~isempty(app.WhichTextGridTierSelected) ...
                    && app.WhichTextGridTierSelected > 0 ...
                    && app.WhichTextGridTierSelected <= numel(TierNames)
                ind = app.WhichTextGridTierSelected; ifSelected = true;
            elseif numel(TierNames) > 1
                [ind,ifSelected] = listdlg('PromptString',{'Select only one tier ',...
                 'in the TextGrid',''},'SelectionMode','single','ListString',TierNames);
                figure(app.UIFigure) % Get focus back on UIFigure
            else 
                % If there is only one tier, then just select the tier without asking. 
                ind = 1; ifSelected = true;
            end          
            if ~ifSelected, success = false; close(UIprogressDialog_handle); SetStatus(app, 'ready');return; end
            SetStatus(app, 'ready'); close(UIprogressDialog_handle);
            app.TextGridTier = TG(ind);
            app.WhichTextGridTierSelected = ind;
        end
        %%
        function drawTextGridIntervals(app)
          % Called only when loading TextGrid.
            if isempty(app.TextGrid), return; end
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading TextGrid labels','Indeterminate','on');
            SetStatus(app, 'busy');
            h = findobj(app.Ax1,'tag', 'TextGrid');
            if ~isempty(h), delete(h); end
            tg = app.TextGridTier;  labs = tg.labs;
            y = app.Ax1.YLim(2); 
            app.hg_textgrid_labels = hggroup(app.Ax1); % Creat handle group for TextGrid labels.
            for i = 1:numel(labs)
                lab = labs{i}; t1 = tg.segs(i,1); 
                h1 = line(app.Ax1, [t1,t1], [0,1], 'color', app.PARAMS.TextGridLineColor, 'linestyle', '-', 'linewidth', 1, 'tag', 'TextGrid'); 
                h1.Color(4) = app.PARAMS.TextGridLineAlpha; 
                if size(tg.segs,2) > 1 % interval tier
                    t = mean(tg.segs(i,:));
                else % point tier
                    t = tg.segs(i);
                end
                text(app.Ax1, t, y, lab, 'fontsize', app.PARAMS.TextGridFontSize, ...
                    'horizontalalignment', 'center', 'tag', 'TextGrid','color', app.PARAMS.TextGridLabelColor,...
                    'VerticalAlignment','top', 'interpreter', 'none', 'clipping', 'on', 'parent', app.hg_textgrid_labels);
            end
            close(UIprogressDialog_handle);
            SetStatus(app, 'ready');
        end %drawTextGridIntervals(app)
        %% 
        function success = CloseVideoFile(app)
            % Called when closing a video file (reading a new video file)
            ifAutosave = app.Ck_Autosave.Value;
            if ifAutosave && app.flag_ifAnchorModifed
                silent = true; Export2Mat(app, silent);
            elseif app.flag_ifAnchorModifed
            % If there are unsaved changes, prompt the user to confirm:
                msg = {'There are unsaved changes to contours in the current file', ...
                    'Continue closing current file? '};
                selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
                if strcmpi(selection, 'Cancel'), success = false; return;end
            end
            success = true;
        end %CloseVideoFile(app)
        %% 
        function axisNum = CurrentCursorWhichAxis(app)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1.Position; % Axis for AudioAll
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
%             axisNum = [];
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                axisNum = 0;
            elseif app.ifWithinPos(fig_pt, pos_Ax1)
                axisNum = 1;
            else 
                axisNum = [];
            end            
        end %CurrentCursorWhichAxis
        %%
        function clearDrawLine(app)
            % Clear/delete/terminate draw line
            app.draw_xy = []; 
            if isgraphics(app.handle_draw), delete(app.handle_draw);end
            app.handle_draw = [];
        end %clearDrawLine(app)
        %%
        function plotDrawLine(app)
            % Plot hand-drawn contour
            if isempty(app.draw_xy), clearDrawLine(app); return; end
            hold(app.Ax0,'on');
            xy = app.draw_xy;
            % First time plotting draw line:
            if isempty(app.handle_draw) || ~isgraphics(app.handle_draw)
                app.handle_draw = plot(app.Ax0, xy(:,1), xy(:,2), 'color', app.draw_color, ...
                    'visible', app.draw_visible, 'LineStyle', app.draw_style, 'LineWidth', app.draw_width);
                app.handle_draw.Color(4) = app.draw_alpha;              
            else
            % Update existing draw line:
                app.handle_draw.Visible = app.draw_visible;
                app.handle_draw.XData = xy(:,1); app.handle_draw.YData = xy(:,2);
                app.handle_draw.Color = app.draw_color; 
                app.handle_draw.LineStyle = app.draw_style; 
                app.handle_draw.LineWidth = app.draw_width; 
                app.handle_draw.Color(4) = app.draw_alpha;  
            end
            drawnow 
            hold(app.Ax0,'off');
        end % updateDrawLine
        %% 
        function Proc_WindowButtonUp_Ax0(app)
         % Procedure of WindowButtonUp in Ax0 (Ultrasound image axis)
                mouseButton = app.UIFigure.SelectionType;
                frame = app.Ed_Frame.Value; 
                [x,y] = Ax0_cursor_loc(app); pt = [x,y];
                anchors = app.ANCHORS{frame}; 
                drawxy = app.draw_xy;  clearDrawLine(app);
                
                %% If SelectRectWindowButton is Down : 
                if app.flag_SelectRectMouseDown
                    app.Msg1.Value = app.defaultMsg; 
                    app.flag_SelectRectMouseDown = false;
                    app.flag_SelectCropRect = false; hold(app.Ax0,'on');
                    app=do_crop(app); app.Sw_ClickAnchor.Value = app.PreviousClickAnchorValue;
                    app.Bn_CropButton.Value = 0; return
                end % Crop
                  %% If Get MPP On : 
                if app.flag_GetMppOn
                    app.Msg1.Value = app.defaultMsg; 
                    app.flag_GetMppOn = false;
                    do_mpp(app); 
                    hold(app.Ax0,'on');
                    return
                end % Crop          
                %% If left-click % Sw_ClickAnchor is 'On':
                if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                        ~app.flag_SelectRectMouseDown && ...
                        isempty(app.selectedAnchorID) && ... % no selected anchor
                       strcmpi(mouseButton, 'normal') % left click
                    if ~app.ifWithinPos(pt, app.crop_rect), 
                        return; 
                    end % if the clicked pt is not within crop_rect
                    if isempty(drawxy), return; end
                        d=sqrt(sum((drawxy(end,1:2)-pt).^2,2)); % distance between current pt and previous pt.
                         if d > app.minDist,  drawxy = [drawxy; pt];  end

                     if size(drawxy,1) > 1
                         len = app.LineLength(drawxy(:,1:2)); 
                         nPt = ceil(len / app.minDist /2); % # points for the draw line
                         if nPt > 2
                             drawxy = app.interpLine(drawxy(:,1:2), nPt); 
                         else
                             drawxy = drawxy([1,end],:);
                         end
                     end

                    drawxy(:, 3)= 1; % add user input index to the 3rd column
                    anchors = [anchors; drawxy]; 
                    anchors = app.sortAnchors(anchors); 
                    updatePlotAnchors(app, anchors, 'USER');    
                    app.flag_ifAnchorModifed = true;
                end % Add anchor

                %% If Right-click & ClickAnchor, Delete anchor:
                if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                        ~app.flag_SelectRectMouseDown && ...
                       strcmpi(mouseButton, 'alt') % Right Click

                    [anchors, app.selectedAnchorID] = app.AddOrSelectAnchor(anchors, pt, app.minDist);
                    % if left click and no existing anchor at this point, then add anchor:
                    % if right click on existing anchor, delete the selected anchor:
                    if ~isempty(app.selectedAnchorID)
                        anchors(app.selectedAnchorID,:)=[]; 
                        updatePlotAnchors(app, anchors, 'USER');      
                        app.flag_ifAnchorModifed = true;
                    end 
                end % Delete anchor
        end % Proc_WindowButtonUp_Ax0
        %%
        function Proc_WindowButtonMotion_Ax0(app)
            % Procedure of WindowButtonMotion (mouse move) in Ax0 (Ultrasound image axis)
           
            % IF no WindowButtonDown event yet, do nothing:
            if ~app.flag_mouseClicked, return; end
             [x,y] = Ax0_cursor_loc(app); pt = [x,y];
             mouseButton = app.UIFigure.SelectionType;

            if app.flag_SelectRectMouseDown
                % Update Cropping rectangle
                app.handle_rect = app.updateXYinPlotRect(app.handle_rect, pt); return
            end % Update Cropping rectangle

            if app.flag_GetMppOn
                % Update Cropping rectangle
                app.handle_mpp = app.updateXYinMppLine(app.handle_mpp, pt); return
            end % Update Cropping rectangle
            
            if ~isempty(app.draw_xy) && app.ifWithinPos(pt, app.crop_rect), 
                 d = app.udist(pt, app.draw_xy(end,:));% distance between current pt and previous pt.
                 if d > app.minDist, app.draw_xy = [app.draw_xy; pt];  else, return; end % update drawxy only when movement is big enough. 
            end
             %% If an anchor is selected & Sw_ClickAnchor is 'On':
             % Left click to move 1 selected anchor
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    ~isempty(app.selectedAnchorID) && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                    strcmpi(mouseButton, 'normal') % left click
                    if ~app.ifWithinPos(pt, app.crop_rect) 
                        clearDrawLine(app); return; 
                    end % if the clicked pt is not within crop_rect
                frame = app.Ed_Frame.Value; anchors = app.ANCHORS{frame}; 
                anchors(app.selectedAnchorID, 1:2) = pt;
                anchors(app.selectedAnchorID, 3) =1; 
                method = 'USER'; 
                updatePlotAnchors(app, anchors, method);
                app.flag_ifAnchorModifed = true;
                app.draw_xy = [];
            end % Move 1 selected anchor

            %% IF no selected anchor & left click move, update draw:
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    isempty(app.selectedAnchorID) && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                  strcmpi(mouseButton, 'normal') % left click
                    if ~app.ifWithinPos(pt, app.crop_rect), 
                        clearDrawLine(app);
                        return; 
                    end % if the clicked pt is not within crop_rect
                  plotDrawLine(app);
            end % update draw

            %% IF no selected anchor & left click move, update draw:
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    isempty(app.selectedAnchorID) && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                  strcmpi(mouseButton, 'alt') % left click
                  plotDrawLine(app);
            end % update draw
        end % Proc_WindowButtonMotion_Ax0

        %%
        function Proc_WindowButtonDown_Ax0(app)
             % Procedure of WindowButtonDown (mouse click) in Ax0 (Ultrasound image axis)
           [x,y] = Ax0_cursor_loc(app); pt = [x,y];
           app.draw_xy = pt;  %% store first click

            %% 'SelectCropRect' pushed:
            if app.flag_SelectCropRect
                app.flag_SelectRectMouseDown = true;
                hold(app.Ax0,'on');  plotRectAx0(app, pt, pt);
                app.flag_SelectCropRect = false;
                app.Msg1.Value = 'Drag to select cropping rectangle....';
            end % Crop
            %% GetMPP:
            if app.flag_GetMppOn;
                if app.flag_SelectCropRect, return; end
                if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, return; end
                hold(app.Ax0,'on'); 
                plotMppLine(app, pt, pt);
                app.Msg1.Value = 'Drag to select 1 cm interval....';
            end % Crop
            %% If Sw_ClickAnchor is 'On':
            % for both left and right click
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn
                frame = app.Ed_Frame.Value; 
                app.selectedAnchorID = []; anchors = app.ANCHORS{frame};
                [~, app.selectedAnchorID] = app.AddOrSelectAnchor(anchors, pt, app.minDist);
            end % first click
        end % Proc_WindowButtonDown_Ax0
        %%
        function Proc_WindowButtonDown_Ax1(app)
            % Procedure of WindowButtonDown (mouse click) in Ax1 (audio axis)
            x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
            mouseButton = app.UIFigure.SelectionType;
            if strcmpi(mouseButton, 'normal') % left click
                app.Ax1ClickLog = x;  %% store first click
            end % if strcmpi(mouseButton, 'normal') % left click
        end% Proc_WindowButtonDown_Ax1
        %% 
        function Proc_WindowButtonMotion_Ax1(app)
            % Procedure of WindowButtonMotion (mouse move) in Ax1 (Audio axis)          
            % IF no WindowButtonDown event yet, do nothing:
            if ~app.flag_mouseClicked, return; end
            if isempty(app.Ax1ClickLog), return;end
             x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
             minSelectDur = app.minAudioSelectDur; 
             firstx = app.Ax1ClickLog(1);lastx = app.Ax1ClickLog(end); 
             if abs(x-lastx) < minSelectDur, return;end
             mouseButton = app.UIFigure.SelectionType;
             if strcmpi(mouseButton, 'normal') % left click
                app.Ax1ClickLog = [firstx; x]; % only store first and last point
                firstx = app.Ax1ClickLog(1); lastx = app.Ax1ClickLog(end); 
                d = abs(lastx - firstx); 
                if d > minSelectDur && isgraphics(app.handle_AudioSelection)
                    plotUpdateAudioCursor(app, firstx, lastx); 
                end
             end %   if strcmpi(mouseButton, 'normal')
        end % Proc_WindowButtonMotion_Ax1(app)
       %% 
       function Proc_WindowButtonUp_Ax1(app)
        % Procedure of WindowButtonUp in Ax1 (Audio axis)   
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, return; end
            if isempty(app.Ax1ClickLog), return;end
            x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
            minSelectDur = app.minAudioSelectDur; 
            firstx = app.Ax1ClickLog(1); lastx = app.Ax1ClickLog(end); 
            if abs(x-lastx) > minSelectDur, app.Ax1ClickLog = [firstx; x]; end 
            firstx = app.Ax1ClickLog(1); lastx = app.Ax1ClickLog(end); 
            d = abs(lastx - firstx);
            frame = floor(firstx * app.vObj.FrameRate) + 1; 
            if isgraphics(app.handle_AudioSelection), 
                if d > minSelectDur
                    plotUpdateAudioCursor(app, firstx, lastx); 
                    app.Msg1.Value = {sprintf('Audio selected from %0.4g to %0.4g (s).', firstx, lastx), ...
                        sprintf('Selected audio duration = %0.4g (s)', d)};
                else
                    plotUpdateAudioCursor(app, firstx); 
                end
            end
            dispFrame(app, frame); updatePlotAnchors(app);   
            app.Ax1ClickLog = [];  % clear Ax1ClickLog
       end % Proc_WindowButtonUp_Ax1        
        %%
        function Export2Mat(app, silent)
            if nargin < 2 || isempty(silent), silent = false; end
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, vFn);
            anchorCells = app.ANCHORS;  xyCells = app.XY; 
            energyCells = app.ENERGY; methodCells = app.METHOD;  timeCells = app.TIME;
            fig = app.UIFigure; 
            nFrames = numel(anchorCells); FRAME = num2cell((1:nFrames)'); %#ok<*ADPROPLC> 
            TRKRES = cellfun(@(x, y) struct('ENERGY', x, 'NPARTICLES', [], 'METHOD', y), energyCells, methodCells, 'uniformoutput', false);
             % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
            anchorCells = cellfun(@rm3rdCol, anchorCells, 'UniformOutput', false); % Remove the 3rd column in anchors 
            xyCells = cellfun(@rm3rdCol, xyCells, 'UniformOutput', false); % Remove the 3rd column in anchors 
            contours = struct('XY', xyCells, 'ANCHORS', anchorCells, 'FRAME', FRAME, 'NOTE', [], ...
                'TRKRES',TRKRES, 'TIME', timeCells, 'IMAGE', []);
            contours(cellfun(@isempty,{contours.XY})) = [];					% remove empty frames
             [p,f,e]=fileparts(outFName);  if isempty(e), outFName = fullfile(p, [f '.mat']); end
             if ~isvarname(f), varN = 'contours'; else, varN = f; end
             tmpStruct.(varN) = contours;
            % If output file exists:
            successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Contours successfully saved.'; 
            if exist(outFName,'file') && ~silent
                msg = 'Saving these changes will overwrite previous changes.';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFName, '-struct','tmpStruct', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);  
                    case 'Save as new'
                        [file, p] = uiputfile(outFName,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFName = fullfile(p, file);  save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                        ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);  
                    case 'Cancel',  return;
                end %switch selection
            elseif silent
                % if silent,  save file no matter file exists or not:
                save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                app.Msg1.Value = 'Contours saved.';
                pause(0.5);
                app.Msg1.Value = app.defaultMsg;
            else
                 % if file does not exist and not silent:
                save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);               
            end %if exist(outFN,'file')       
            app.count_modified_frames = 0; 
            app.flag_ifAnchorModifed = false;
            function c = rm3rdCol(c)
                % Remove the 3rd column in anchors 
                if ~isempty(c),  c = c(:,1:2); end
            end % function c = rm3rdCol(c)   
       end %function Export2Mat
       %%
       function ExportCropPara(app, outFN, crop_para)
            fig = app.UIFigure; successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Cropping parameters successfully saved.'; 
            % If output file exists:
            if exist(outFN,'file')
                msg = 'Cropping parameter file exists. Overwrite?';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFN, 'crop_para', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Save as new'
                        [file, p] = uiputfile(outFN,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFN = fullfile(p, file);   save(outFN, 'crop_para', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Cancel',  return;
                end %switch selection
                return
            else 
                 save(outFN, 'crop_para', app.matFileVersion); 
                 ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
            end %if exist(outFN,'file')
        end %  function ExportCropPara
       %%
        function ShowSuccessMsg(app, title, msg, waitTimeSec)
            fig = app.UIFigure; d = uiprogressdlg(fig,'Title', title, 'Message',msg,'value', 1);
            pause(waitTimeSec); close(d)
        end %ShowSuccessMsg
        %%
        function plotRectAx0(app, p1, p2, varargin)
            % plot a rectangle from 2 corners: p1, p2 : [x,y] coordinates;  
            x = [p1(1), p1(1), p2(1), p2(1), p1(1)];   y = [p1(2), p2(2), p2(2), p1(2), p1(2)]; 
            app.handle_rect = plot(app.Ax0, x,y, 'marker', app.rect_marker,'color', app.rect_color, ...
                'linestyle', app.rect_linestyle, 'linewidth', app.rect_linewidth, varargin{:});  
        end %function plotRectAx0(ax, p1, p2, varargin)
        %%
        function plotMppLine(app, p1, p2, varargin)
            % plot MPP line of 1 CM from 2 points: p1, p2 : [x,y] coordinates;  
            app.handle_mpp = plot(app.Ax0, [p1(1), p2(1)], [p1(2), p2(2)], 'marker', app.mpp_marker, ...
                'color', app.mpp_color, 'linestyle', app.mpp_linestyle, 'linewidth', app.mpp_linewidth, varargin{:});  
        end %function plotMppLine(ax, p1, p2, varargin)
        %%
        function busy = isBusy(app)
           % Check if status is busy
            busy = false; 
            if app.flag_GetMppOn, busy = true; return; end
            if app.flag_SelectCropRect, busy = true; return; end
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, busy = true; return; end
            if app.flag_mouseClicked, busy = true; return; end
            if app.flag_busy, busy = true; return; end
        end % isBusy
        %%
        function RefreshAudioAxis(app, newXlm)
            oldXlm = app.Ax1.XLim; currentDur = oldXlm(2) - oldXlm(1);  
            if nargin < 2 || isempty(newXlm), newXlm = [app.AudioCursorTime, app.AudioCursorTime+currentDur]; end

            audioSig = app.AUDIO.s; audioSr = app.AUDIO.sr; audioDur = app.AUDIO.Dur; minDispDur = app.minAudioDisplayDur;
            newDur = newXlm(2) - newXlm(1); 
            if newDur < minDispDur, return; end
            if newDur > audioDur, return;end
            left = newXlm(1); right = newXlm(2);
            if left < 0, left = 0; right = newDur; end
            if right > audioDur, left = audioDur - newDur; right = audioDur; end
            x = [left, right]; n = floor(x*audioSr) +1; 
            if n(1) < 1, n(1) = 1;end
            if n(2) > size(audioSig,1), n(2) = size(audioSig,1);end
            s1 = audioSig(n(1):n(2));  ylm = [min(s1), max(s1)];
            app.Ax1.XLim = x; app.Ax1.YLim = ylm;
            app.minAudioSelectDur = newDur / app.minAudioSelectionDenominator;
            if ~isempty(app.AudioCursorTime)
                plotUpdateAudioCursor(app, app.AudioCursorTime);
            end
            if ~isempty(app.TextGrid) && ~isempty(app.hg_textgrid_labels)
                updateTextGridLabels(app);
            end
            if ~isempty(app.handle_diagnosis) && isgraphics(app.handle_diagnosis)
                plotUpdateDiagnosis(app);
            end
        end %RefreshAudioAxis
        %% 
        function updateTextGridLabels(app)
            if isempty(app.TextGrid) || isempty(app.AUDIO), return;end
            ylm = app.Ax1.YLim;  xlm = app.Ax1.XLim; 
            hg = app.hg_textgrid_labels; pos = cell2mat({hg.Children.Position}');
            ix = pos(:,1) > xlm(1) & pos(:,1) < xlm(2); 
            if ~any(ix), return; end
            ind = find(ix)'; 
            for i = ind
                try
                     hg.Children(i).Position(2) = ylm(2); 
                catch
                end
            end
        end %updateTextGridLabels
        %%
        function RunDiagnosticFilters(app)
              diag = app.diagnosticMatrix;
              app.BadContourFrameIndex=[];
             % diag: nFrame x 4 (energy mean, max, q3, roughness)
             n = size(diag,1); m = size(diag,2); ix = false(n, size(diag,m));  
             checkValues = [app.Ck_EnergyMeanFilter.Value, ...
                 app.Ck_EnergyMaxFilter.Value, app.Ck_EnergyQ3Filter.Value, app.Ck_RoughnessFilter.Value];
             threshs =  [app.Ed_EnergyMeanFilter.Value, ...
                 app.Ed_EnergyMaxFilter.Value, app.Ed_EnergyQ3Filter.Value, app.Ed_RoughnessFilter.Value];
             for i = 1:m
                 ix(:,i) = diag(:,i) > threshs(i) & repmat(checkValues(i),n,1);
             end 
             app.BadContourFrameIndex = find(any(ix,2));
             app.Msg1.Value = sprintf('Found %d frames with high (undesired) energy.', numel(app.BadContourFrameIndex));
        end%RunDiagnosticFilters(app)
        %%
        function plotUpdateDiagnosis(app)
            hold(app.Ax1,'on');
            diagFrames = app.BadContourFrameIndex; n = numel(diagFrames);
            if n == 0 && ~isempty(app.handle_diagnosis) && isgraphics(app.handle_diagnosis)
                delete(app.handle_diagnosis); app.handle_diagnosis = []; return;
            end 
            if n == 0, return; end
            x = (diagFrames -1) ./ app.vObj.FrameRate;
            y = repmat(app.Ax1.YLim(1), n, 1);
%             y = zeros(n,1);
            if isempty(app.handle_diagnosis) || ~isgraphics(app.handle_diagnosis)
               % First time plotting handle_diagnosis:
                app.handle_diagnosis = plot(app.Ax1, x, y, 'color', app.diagnosis_color, 'LineStyle', 'none', ...
                    'visible', app.diagnosis_visible, 'Marker', app.diagnosis_marker, 'MarkerSize', app.diagnosis_marker_size, 'clipping', 'on');
                app.handle_diagnosis.Color(4) = app.diagnosis_alpha;
            else
% %               % Update existing handle_diagnosis:
                app.handle_diagnosis.XData = x; app.handle_diagnosis.YData = y; 
                app.handle_diagnosis.LineStyle = 'none';
            end    
            hold(app.Ax1, 'off');
        end %plotUpdateDiagnosis(app)
        %%
        function [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method)
            % status = 0 : error;  1: success;  2: user stopped
            status = 0;
            for iFrame = StartFrame : EndFrame
                if iFrame > 1 
                    previous_anchors = app.ANCHORS{iFrame-1};
                    current_anchors = app.ANCHORS{iFrame};
                    if size(previous_anchors,1) < app.minSnakeAnchors
                        anchors = current_anchors;
                    else
                        anchors = previous_anchors;
                    end
                elseif iFrame == 1
                    anchors = app.ANCHORS{iFrame};
                end
                if strcmpi(method, 'SNAKE') &&  size(anchors,1) < app.minSnakeAnchors
                    app.Msg1.Value = ['Snake requires at least ' num2str(app.minSnakeAnchors) '!'];
                    app.Bn_StopTrack.Value = false; %reset the button state
                    app.Bn_TrackFrames.Value = false;  app.Bn_TrackFiles.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false; SetStatus(app, 'ready');  
                    break
                end
                
                im= read(app.vObj, iFrame); 
                [anchors, energy, xy] = detectOneFrame(app, im, method, anchors);
                app.ANCHORS{iFrame} = anchors; app.XY{iFrame} = xy; app.ENERGY{iFrame} = energy; app.METHOD{iFrame} = method; 
                dispFrame(app, iFrame);updatePlotAnchors(app); app.flag_ifAnchorModifed = true;
                if isgraphics(app.handle_AudioSelection), plotUpdateAudioCursor(app, app.Ed_Time.Value); end
                % If user stopped:
                if app.Bn_StopTrack.Value 
                    app.Bn_StopTrack.Value = false; %reset the button state
                    app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
                    status = 2; % status = 0 : error;  1: success;  2: user stopped
                    EndFrame = iFrame -1;
                    SetStatus(app, 'ready');   break
                end % Bn_StopTrack
                % Pause between frames:
                if iFrame < EndFrame, pause (app.TrackingDelayMs/1000); end
                if app.Ck_Autosave.Value && app.flag_ifAnchorModifed
                    app.count_modified_frames = app.count_modified_frames +1;
                end
                if app.Ck_Autosave.Value && app.count_modified_frames >= app.Ed_savingFreq.Value;
                    silent = true; Export2Mat(app, silent); 
                    [~, vFn, ~]= fileparts( app.vObj.Name); matFn = [vFn, '.mat'];
                    fprintf([matFn ': saved Frame #'  num2str( iFrame) ' \n']);
                end
                status = 1;
            end %for i = frame:app.nFrames
%             status = 1; % status = 0 : error;  1: success;  2: user stopped
        end % TrackThruAllFrames(app)
    end % method (Access=private)
    
    %% Below are supporting functions
    %% Call by app.FUNCTIONNAME
    methods (Access = public, Static=true)
        %%
                %%

        %%
        function hg = UpdateMaskBoundary(mask, hg)
            nPts = 200;  b = bwboundaries(mask);   b=interpLine_simple(b{1}, nPts, 'makima');   bx = b(:,2); by = b(:,1); 
            for i = 1:2,   h = hg.Children(i); clearpoints(h); addpoints(h, bx, by);  end
            drawnow
            function OutputLine = interpLine_simple(InputLine, density, method)
                %  Simplified version of interpLine;  {See also interpLine.m} InputLine: n (points) x m (dimenstions) data points;  OutputLine: 'density' x m (dimenstions) data points
                d = sqrt(sum(diff(InputLine).^2,2)); cumDist = [0; cumsum(d)]; lineLength = cumDist(end); newCumDist = linspace(0, lineLength, density); OutputLine = interp1(cumDist, InputLine, newCumDist, method);
            end %function [OutputLine, lineLength1] =interpLine_simple
        end %function hg = UpdateMaskBoundary(mask, hg)
        %
        function [len, dist] = LineLength(input_line)
            % This function calculates the length of a line by using Euclidean distance. 
            % 'input_line':  n(Points) x m (dimensions) matrix for the coordinates of the points of the input line.
            %  'len' : length of input_line   %  'dist' : distances of each segment along the line
            d = diff(input_line); dist = sqrt(sum(d.^2,2)); len =sum(dist);
        end %LineLength
        % 
        function handle = updateXYinPlotRect(handle, p2)
            % Update the 'XData', and 'YData' in the handle of plotted object.
            % p1, p2 : [x,y] coordinates;  
            x1 = handle.XData(1); y1 = handle.YData(1);   x2 = p2(1); y2 = p2(2); 
            x = [x1, x1, x2, x2, x1];  y = [y1, y2, y2, y1, y1]; handle.XData = x;  handle.YData = y;
        end % updateXYinPlotRect(h, p2)
        function handle = updateXYinMppLine(handle, p2)
            % Update the 'XData', and 'YData' in the handle of plotted object.
            % p1, p2 : [x,y] coordinates;  
            if isempty(handle) || isempty(p2)
                return
            end
            x1 = handle.XData(1); y1 = handle.YData(1); x2 = p2(1); y2 = p2(2);  
            x = [x1, x1];  y = [y1, y2]; 
            handle.XData = x;  handle.YData = y;
        end % updateXYinMppLine(h, p2)
%      %
        function hg = DisplayMaskBoundary(ax, mask, color1, color2, edgealpha)
            if nargin<3 || isempty(color1), color1 = 'w'; end
            if nargin<4 || isempty(color2), color2 = 'b'; end
            if nargin<5 || isempty(edgealpha), edgealpha = 0.3; end
            nPts = 200; hg = hggroup(ax);
            b = bwboundaries(mask); 
            b = interpLine_simple(b{1}, nPts, 'makima');
            x = b(:,2); y = b(:,1); 
            h1 = animatedline(ax,x, y, 'linewidth', 2, 'Marker', 'none', 'color', color1,'Parent',hg); h1.Color(4)= edgealpha; 
            h2 = animatedline(ax, x, y, 'linewidth', 1, 'Marker', 'none', 'color', color2,'Parent',hg); h2.Color(4)= edgealpha; 
            function OutputLine = interpLine_simple(InputLine, density, method)
                %  Simplified version of interpLine;  {See also interpLine.m} InputLine: n (points) x m (dimenstions) data points;  OutputLine: 'density' x m (dimenstions) data points
                d = sqrt(sum(diff(InputLine).^2,2)); cumDist = [0; cumsum(d)]; lineLength = cumDist(end); newCumDist = linspace(0, lineLength, density); OutputLine = interp1(cumDist, InputLine, newCumDist, method);
            end %function [OutputLine, lineLength1] =interpLine_simple
        end % function hg = DisplayMaskBoundary
        %
        function [mask, bw] = GenMaskFromAnchors(anchors, sigma, w, h)
            % w : image width; h : image height; anchors : [x, y] coordinates
            % anchors must be sorted before calling this function
            nPts = 200;  %anchors = sortAnchors(anchors);
            xy = interpLine_simple(anchors,nPts, 'makima');%
            % Downsample then upsample to increase speed:
            s = 10; % downsample scale
            xy1 = xy /s; w1 = round(w /s); h1 = round(h /s);
            mask1 = xy2mask(xy1(:,1), xy1(:,2),  w1, h1, sigma); 
            mask2 = imresize(mask1, [h, w]);  mask = imgaussfilt(mask2,3); bw = mask>0.5;
            
            function mask = xy2mask(x, y,  w, h, sigma)
                % Convert [x,y] coordinates on an image to a mask with the same size of the image, using Gaussian kernels.
                % INPUT:  x : n x 1 x-axis coordinates;  y : n x 1 y-axis coordinates;  w : width;   h : height;  sigma : pixel to calculate Gaussian kernel.; OUTPUT:  mask:  h x w (0~1) mask 
                mask = zeros(h,w); sigSq2 = 2*sigma^2;
                for i =1:w,  for j = 1:h, intensity = sum(exp(-1 * (((i-x).^2 + (j-y).^2) ./ sigSq2)));  mask(j,i) = intensity; end; end
            end  %function mask = xy2mask(x, y,  w, h, sigma)
            function out = interpLine_simple(in, nOut, method) %in:nIn x mDims; out:nOut x mDims
                cumD = [0; cumsum(sqrt(sum(diff(in).^2,2)))];  out = interp1(cumD, in, linspace(0, cumD(end), nOut), method);
            end %function out =interpLine_simple
        end % function [mask, bw] = GenMaskFromAnchors  
        %
        function crop_rect = get_rect_from_plot_handle(h)
            % 'crop_rect' : [xmin ymin width height]  cropping rectangle  
            x = h.XData;  y = h.YData; p1x = min(x); p1y = min(y); p2x = max(x); p2y = max(y);  crop_rect = round([p1x, p1y, p2x-p1x+1, p2y-p1y+1]);
        end % get_rect_from_plot_handle
        %
        function [anchors, selectedID] = AddOrSelectAnchor(anchors, pt, dist_epsilon)
            % anchors: n x 3 [x,y, isUser] coordinates of anchor points.
            % pt: [x,y] coordinates of clicked location.
            % dist_epsilon: distance tolerance to determine whether the current click coincides with existing anchors
            % If the clicked point 'pt' coincides with an existing point in 'anchors',
            % then the sequence number of the clicked point is returned as
            % 'selectedID', and the same 'anchors' will be returned. 
            %  anchors were sorted from left to right.
            selectedID = [];  pt1 = [pt, 1]; % the 3rd element denotes user input
            if nargin<3, dist_epsilon = 3; end
            if isempty(anchors), anchors = pt1; return; end
            d = sqrt(sum((anchors(:,1:2)-pt).^2,2));
            [sorted_d, ind ] = sort(d);
            d_min = sorted_d(1);
            if d_min < dist_epsilon, selectedID = ind(1); return; end
            anchors = [anchors; pt1];
         end % function [anchors, selectedID] =AddOrSelectAnchor(anchors, pt, dist_epsilon) 
        %
        function sortedAnchors = sortAnchors(anchors, ifAscend)
            % Sort the anchor points (x,y coordinates) on ultrasound tongue contours. 
            % 'anchors' :  n x {x, y + other meta information} 
            % ' ifAscend'  set to true ->  sort points from left to right {default}
            %                     false ->  sort points from right to left
            %  Weirong Chen    Feb-5-2020
            if nargin < 2 || isempty(ifAscend), ifAscend = true; end
            if isempty(anchors), sortedAnchors = [];return; end
            [~, ia,~] = unique(anchors(:,1:2),'rows', 'stable');
            anchors = anchors(ia,:);
            if size(anchors, 1) < 2, sortedAnchors = anchors; return; end

            x  = anchors(:,1); y = anchors(:,2); n = numel(x);
            sortedAnchors = NaN(size(anchors)); tmpAnchors = anchors;
            [~,Ind]=max(y); % find lowest anchor point on image (***max in y)
            pt = anchors(Ind,:); tmpAnchors(Ind,:) = []; sortedAnchors(1,:) = pt;
            for i = 2:n
                d = sqrt(sum((tmpAnchors(:,1:2)-pt(:,1:2)).^2,2));
                [~,Ind] = min(d); pt = tmpAnchors(Ind,:);  sortedAnchors(i,:) = pt; tmpAnchors(Ind,:) = []; 
            end
            %% Set the direction of sorting:
            if ifAscend
                if  sortedAnchors(1,1) > sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            else
                if  sortedAnchors(1,1) < sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            end
        end %function sortedAnchors = sortAnchors(anchors, ifAscend)
        %
        function in = ifWithinPos(pt, pos)
            % check if the (x,y) position is within the region defined by 'pos' : left bottom width height
            x = pt(1); y = pt(2); [xv, yv] = pos2xy(pos); in = inpolygon(x,y, xv, yv);
                function [xv,yv]=pos2xy(p)
                    % Converting positional values 'pos' (left bottom width height) into 4 pairs of x-y coordinates.
                    % The output x-y pairs starts from lower left corner counterclockwisely. 
                    x1 = p(1); x2 = x1+p(3); x3 = x2; x4 = x1; y1 = p(2); y2 = y1; y3 = y1+p(4); y4 = y3;
                    xv = [x1; x2; x3; x4]; yv = [y1; y2; y3; y4];
                end %function [x,y]=pos2xy
        end %function in = ifWithinPos(pt, pos)  
        %
        function var=load1var(matfilename, varname)
            % Load 1 variable from a .mat file
            % If 'varname' is empty, load the first variable in the file.
            if ~exist(matfilename,'file'), var = []; return; end
            varlist = who('-file', matfilename);
            if nargin < 2 || isempty(varname), varname = varlist{1}; end
            ix = ismember(varlist, varname);
            if ~any(ix), var = []; return; end
            var = load(matfilename, varname); 
            %var = eval(['var.',varname,';']);	 %#ok<*NASGU>
            var = var.(varname);
        end % end of function var=load1var(matfilename, varname)
        %
        function yes = hasfield(struct, fieldname)
            yes = any(ismember( fieldnames(struct), fieldname));
        end % function x = hasfield(s, f)
        %
        function [OutputLine, lineLength] = interpLine(InputLine, density, method)
            %  Interpolation for a line.
            % InputLine: n (points) x m (dimenstions) data points
            % OutputLine: 'density' x m (dimenstions) data points
            % {'density' > n} 
            % 'method': interpolation method. default value = 'spline';
            % W. Chen   20JUL2021
            if nargin<3, method='spline';end; if nargin<2, density=50; end
            if isempty(InputLine), OutputLine = []; lineLength = []; return; end
            InputLine = unique(InputLine, 'rows', 'stable'); n=size(InputLine,1);
            if n == 1, OutputLine = repmat(InputLine, density,1);return;end
            cumDist = [0; cumsum(sqrt(sum(diff(InputLine).^2,2)))]; % cumulative distances along the line
            OutputLine = interp1(cumDist, InputLine,linspace(0, cumDist(end), density), method);
            d1 = sqrt(sum(diff(OutputLine).^2,2)); newCumDist1 = [0; cumsum(d1)]; lineLength = newCumDist1(end);
        end %function [OutputLine, lineLength1] =interpLine
        %
        function [s, sr, Dur] = ImportAudio_simple(input, resampleRate)
            if nargin<2 || resampleRate==0,  resampleRate = []; end
            [s,sr] = audioread(input); 
            if size(s,2)>1, s = mean(s(:,1:2),2); end 
            s = (s - mean(s)); s = s ./ max(abs(s)); Dur = length(s) / sr; 
            if ~isempty(resampleRate) && sr~=resampleRate,  s = resample(s,resampleRate,sr); sr = resampleRate; end
        end % function ImportAudio_simple
        %%
        function [TextGridStruct,tierNames,TierStartLineNum, IsPointTier]= ReadTextGrid(tgFName, whichTier)
        % This function reads TextGrid file and returns a struct of TextGrid.
        % INPUT: 
        %      'tgFName' :  TextGrid filename.
        %      'whichTier' :  Specify which tier will be parsed and returned. Leave blank to output all tiers.  
        % Output : 
        %   'TextGridStruct' : TextGrid structure with the following format: 
        %       TextGrid(i).NAME : tier name
        %       TextGrid(i).segs :
        %            n Intervals x 2 ([StartTime EndTime]) matrix, if  this tier is interval tier; 
        %            n Intervals x 1 ([Time]) array, if  this tier is point tier; 
        %       TextGrid(i).labs=labs : n Intervals x 1 cell array
        %       TextGrid(i).IsPointTier= 0 : if this tier is interval tier; 
        %                                         1 : if this tier is point tier; 
        % 
        % Wei-rong Chen  Apr-19-2016
        % Update:  Aug-1-2018:  Fix the issue of double quotation marks. 
        %                      double quotation " is stored as duplication: ""
        %                      in Praat TextGrid internally. 
        %                Oct-2021: Added tier selection
        %                Mar-2022: Embeded supporting functions within the main function. 
        
        [~,~,e]=fileparts(tgFName); 
        if isempty(e), tgFName=[tgFName '.TextGrid'];end
        if ~exist(tgFName, 'file'), error('File NOT exist!');end
        [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(tgFName); 
        [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines);
        if nargin < 2 || isempty(whichTier)
            tierInd = 1:numel(tierNames);
        elseif isnumeric(whichTier)
            tierInd = whichTier;
        elseif ischar(whichTier) 
            tierInd = find(ismember(tierNames, whichTier));
        elseif iscell(whichTier)
            j = 1;
            for i = 1:numel(whichTier)
                k = find(ismember(tierNames, whichTier{i}));
                if isempty(k),  continue; end
                tierInd(j) = k;  j = j+1;
            end
        else 
            tierInd = 1:numel(tierNames);
        end
        % Check if 'whichTier' doesn't exist in the TextGrid
        totalTierInds = 1:numel(tierNames);
        if isempty(tierInd) || any(~ismember(tierInd, totalTierInds))
            error('Tier NOT exist!');
        end
        k = 1; 
        for i=tierInd
            tierName=tierNames{i};
            isPoint = IsPointTier(i); tierStart = TierStartLineNum(i);
            [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart);
            TextGridStruct(k).NAME=tierName;TextGridStruct(k).segs=segs; %#ok<*AGROW>
            TextGridStruct(k).labs=labs; TextGridStruct(k).IsPointTier=isPoint;
            k = k+1;
        end
        %% Supporting sub-functions:
                %% 
                function [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart)
                    nSegs = str2double(lines{tierStart+3}(regexp(lines{tierStart+3},'\d'):end));
                    M = 4 - isShort - isPoint;
                    StartLineNum = tierStart + 4;  EndLineNum = nSegs*M + StartLineNum - 1;
                    nIntervals = (EndLineNum - StartLineNum + 1) / M;
                    items = reshape(lines(StartLineNum:EndLineNum),M,nIntervals)';
                    labs = items(:,M);  kk = cellfun(@strfind, labs,repmat({'"'}, length(labs),1), 'UniformOutput',false);
                    idx1 = num2cell(cellfun(@(x) x(1), kk)+1); idx2 = num2cell(cellfun(@(x) x(end), kk)-1);
                    labs = cellfun(@(x, a,b) x(a:b), labs, idx1, idx2, 'UniformOutput',false);
                    for ilab = 1:numel(labs)
                        lab = labs{ilab};  labs{ilab} = strrep(lab, '""', '"');
                    end
                    lines1 = items(:,2-isShort:(M-1));
                    segs = cellfun(@str2double,regexprep(lines1, '[^0-9.]', ''));
                end %ParseOneTier
                %%
                function [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(fName)
                    encoding = DetectTextGridEncoding(fName);
                    try
                        lines = txt2cell(fName, encoding);
                    catch
                        error('error attempting to load from %s', fName);
                    end
                    lines=DetectStrainedQuotationSybol(lines);
                    % format checking
                    if length(lines)<15 || isempty(strfind(lines{1},'ooTextFile')) || isempty(strfind(lines{2},'"TextGrid"')) || isempty(strfind(lines{6},'exists'))    
                        error('%s : unrecognized file format', fName);
                    end
                    [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(lines);
                end %ReadValidateDataProc

                %%
                function [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines)
                % get tier names and types
                    pointTierNames = {};pointTiers=[];
                    intTierNames = {};intTiers =[];   
	                ind1 = find(~cellfun(@isempty,regexp(lines,'TextTier')));
                    if ~isempty(ind1)
		                pointTiers = ind1 + 1;  TierNameLines = lines(pointTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        pointTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    
	                ind1 = find(~cellfun(@isempty,regexp(lines,'IntervalTier')));
                    if ~isempty(ind1)
		                intTiers = ind1 + 1; TierNameLines = lines(intTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        intTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    tierNames = [pointTierNames;intTierNames];   TierStartLineNum = [pointTiers;intTiers]; 
                    IsPointTier = [true(size(pointTiers)); false(size(intTiers))]; 
                    [~, TierOrder]=sort(TierStartLineNum); tierNames = tierNames(TierOrder); 
                    TierStartLineNum = TierStartLineNum(TierOrder);  IsPointTier = IsPointTier(TierOrder);
                end %GetTierNamesAndTypes
                %%
                function [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(TGlineCells)
                try
                    metaData = TGlineCells([4 5 7]);
                    idx1 = ~isempty(strfind(lower(metaData{1}),'xmin')); %#ok<*STREMP>
                    idx2 = ~isempty(strfind(lower(metaData{2}),'xmax'));
                    idx3 = ~isempty(strfind(lower(metaData{3}),'size'));
                    if idx1 && idx2 && idx3
                        isShort = 0;
                        data =str2double(regexprep(metaData,'(XMIN|XMAX|SIZE|xmin|xmax|size|=| )',''));
                    elseif ~idx1 && ~idx2 && ~idx3
                        isShort = 1; data = str2num(char(metaData)); %#ok<ST2NM>
                %         data = cellfun(@str2double, metaData);
                    else 
                        error('%s has unrecognized file format', fName);
                    end
                catch
	                error('%s has unrecognized file format', fName);
                end
                startTime = data(1); totalDur = data(2); nTiers = data(3);
                end % end of DetectTextGridCellsLongOrShort
                
                function outTextGridLines=DetectStrainedQuotationSybol(TextGridLines)
                    outTextGridLines = TextGridLines;ToDelete=[];
                    for iii = 1:length(TextGridLines)-1
                        thisLine = TextGridLines{iii};nextLine = TextGridLines{iii+1};
                        thisLine = strrep(thisLine,' ','');nextLine = strrep(nextLine,' ','');
                        if strcmp(thisLine,'text="') && strcmp(nextLine(end),'"')
                            ToDelete = [ToDelete iii+1];  outTextGridLines{iii} = [TextGridLines{iii} TextGridLines{iii+1}];
                        end
                    end
                    outTextGridLines(ToDelete) = [];
                end %DetectStrainedQuotationSybol2
                %%
                function encoding = DetectTextGridEncoding(TextGridFName)
                % Detect the text encoding method of a PRAAT .TextGrid file.
                % Usage: encoding = DetectTextGridEncoding(TextGridFName)
                    [~,~,ext]=fileparts(TextGridFName);
                    if isempty(ext), TextGridFName=[TextGridFName '.TextGrid'];end
                    encodings{1}='UTF-8'; encodings{2}='UTF-16BE'; encodings{3}='UTF-16LE';
                    encodingWeight=NaN*zeros(1,length(encodings));
                    wid='MATLAB:iofun:UnsupportedEncoding';
                    warning('off',wid);
                    for jjj=1:length(encodings)
                        fid = fopen(TextGridFName, 'r', 'l', encodings{jjj});
                        S = fscanf(fid, '%c'); fclose(fid);
                        out = strfind(S, 'Text'); encodingWeight(jjj)=length(out);
                    end
                    [~,idx]=max(encodingWeight);
                    encoding=encodings{idx};
                    warning('on',wid);
                end %DetectTextGridEncoding
                %%
                function lines = txt2cell(filename, encoding)
                    % Read text file into cell array. Each line is stored in one cell in a cell array.
                    % Requires MATLAB R2016b or newer. 
                    % W. Chen   APR-24-2021
                    if ~exist(filename, 'file'), lines = {}; fprintf('txt2cell: file not found.\n'); return; end
                    permission = 'r'; % read file in text mode. See 'help fopen'.
                    machinefmt = 'n'; % reading or writing in bytes or bits. 'n' = 'native' {default}
                    if nargin < 2 || isempty(encoding)
                        % if encoding is not specified, fopen will automatically detect the characterset set. 
                        fid = fopen(filename,permission, machinefmt);
                    else
                        fid = fopen(filename,permission, machinefmt, encoding);
                    end
                    s = fscanf(fid, '%c'); fclose(fid);
                    % if the first character is BOM, then remove it:
                    if unicode2native(s(1))==26, s = s(2:end);end 
                    % 'splitlines' is a new funciton since R2016b, which handles different EOLs.
                    lines = splitlines(s); 
                end  % end of  txt2cell
                %%
        end %ReadTextGrid()
         %% 
        function d = udist(pt1,pt2)
            % Calculate the Euclidean distance from pt2 (one or more points) to pt1 (multiple points)
            %   If pt2 = 1 x n (dims) and pt1 = m (points) x n (dims), output 'd' is the distances from pt2 to each elements in pt1.
            %   If both pt1 and pt2 are m (points) x n (dims), 'd' is an array of the point-to-point distance for each pair of [pt1i, pt2i]. 
            % INPUT:  pt1, pt2 : m (points) x n (dimensions) pt1 must be m x n matrix; pt2 can be either 1 x n vector or m x n matrix.    
            % OUTPUT: d : m (points) x 1 vector
            nPointsPt2=size(pt2,1); nPointsPt1=size(pt1,1); dimsPt1=size(pt1,2); dimsPt2=size(pt2,2);        
            if dimsPt1~=dimsPt2, error('dimensions not match!');end % if dimsPt1~=dimsPt2, return d=0.
            if nPointsPt2==nPointsPt1, d=sqrt(sum((pt1-pt2).^2,2)); elseif nPointsPt2==1 && nPointsPt1>1, arr1=pt1; arr2=repmat(pt2,nPointsPt1,1);  d=sqrt(sum((arr1-arr2).^2,2)); end
        end % udist
         %%
        function roughness = CalcSnakeRoughness(xy)
            %% Calculate roughness(1-smoothness) in SNAKE (active contour) algorithm 
            %    defined by Akgul & Kambhamettu (1999) 
            %    roughness ranges between 0 (least rough) to 1 (most rough)
                d=sqrt(sum((xy(1:end-1,:)-xy(2:end,:)).^2,2)); v = xy(2:end,:) - xy(1:end-1,:); 
                roughness = 1 - dot(v(1:end-1,:), v(2:end,:), 2) ./ (d(1:end-1) .* d(2:end))  ;
        end% CalcSnakeRoughness
        %%
        function diag = DiagnoseAnchors(ANCHORS, ENERGY)
            %% Calculate diagnostic matrix for anchors. 
            % INPUT: 
            %    ANCHORS: nFrames x1 cell. Each cell contains N x 2 (x,y) anchor coordinates.
            %    ENERGY: nFrames x1 cell. Each cell contains N x 1 SNAKE energy
            % OUTPUT: 
            %       diag:  nFrames x 4 matrix. 
            %      Each row contains SNAKE energy: mean, max, Q3, and roughness
            % WR Chen   15APR2022
                n = numel(ANCHORS); diag = NaN(n, 4);
                for i = 1:n
                    en = ENERGY{i}; 
                    if sum(~isnan(en), 'all') < 3 || ~isnumeric(en) || length(en) < 3, continue;end
                    enMean = mean(en, 'omitnan'); 
                    enMax = max(en);   q3 = quantile(en, 0.75);
                    mat = [enMean, enMax, q3];
                    diag(i, 1:numel(mat)) = mat;
                end
                for i = 1:n
                    anc = ANCHORS{i}; 
                    if sum(~any(isnan(anc),2))<3 || ~isnumeric(anc) || length(anc) < 3, continue;end
                    roughness = mean(CalcSnakeRoughness(anc), 'omitnan'); diag(i,end) = roughness;
                end
                 function roughness = CalcSnakeRoughness(xy)
                    %% Calculate roughness(1-smoothness) in SNAKE (active contour) algorithm 
                    %    defined by Akgul & Kambhamettu (1999) 
                    %    roughness ranges between 0 (least rough) to 1 (most rough)
                    d=sqrt(sum((xy(1:end-1,:)-xy(2:end,:)).^2,2)); v = xy(2:end,:) - xy(1:end-1,:); 
                    roughness = 1 - dot(v(1:end-1,:), v(2:end,:), 2) ./ (d(1:end-1) .* d(2:end))  ;
                end% CalcSnakeRoughness
        end % DiagnoseAnchors
    end %methods (Access = public, Static=true)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if verLessThan('matlab', app.minMatlabVer)
                app.Msg1.Value = {'DeepEdge failed to start.', ...
                    ['Requires MATLAB ' app.minMatlabVerName ' or newer.']}; return
            end
            initialize(app);
            %% SNAKE parameters:
            app.snakeDefaultPara.Sigma = 5;  app.snakeDefaultPara.Delta = 2; 
            app.snakeDefaultPara.Alpha =0.7;   app.snakeDefaultPara.Lambda1 =0.7; 
            app.snakeDefaultPara.BandPenalty = 2; 
            app.snakePara = app.snakeDefaultPara; 

            defaultmodelname = 'DpEdg_*.mat'; 
            modelFNameMask = fullfile(app.appPath, defaultmodelname);
            fl = getfilelist(modelFNameMask);
            if ~isempty(fl)
                modelFName = fullfile(app.appPath, fl{1});
                if exist(modelFName, 'file'),loadModel(app, modelFName);end
            end
            %% Default video for testing -- delete this part later:
%             myloadvideo(app, 'video01.avi'); dispFrame(app, 1);
            function filelist = getfilelist(mask),FLs = dir(mask); filelist = {FLs.name}'; end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~app.active, return; end
%             if isBusy(app), return;end
            app.flag_mouseClicked = true; mouseButton = app.UIFigure.SelectionType; 
            app.Msg1.Value = app.defaultMsg; 
            switch mouseButton
                case 'normal' % left click
                    app.flag_LeftClickDown = true; 
                case 'alt' % right click or control + left click
                    app.flag_RightClickDown = true;
                case 'open' % double click
                case 'extend' % shift+left click, or middle click, or left+right click
            end
            
            axisNum = CurrentCursorWhichAxis(app);%app.Ax0: axisNum=0;   app.Ax1: axisNum=1
            if isempty(axisNum), return; end % cursor not in any axis

           %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  axisNum == 0
                Proc_WindowButtonDown_Ax0(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax0)
           %% --- IF cursor is within Audio axis (app.Ax1):
            if  axisNum == 1 && ~isempty(app.AUDIO)
                Proc_WindowButtonDown_Ax1(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax1)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~app.active, return; end 
%             if isBusy(app), return;end
            app.flag_mouseClicked = false; app.flag_LeftClickDown = false; app.flag_RightClickDown = false; 
            
            axisNum = CurrentCursorWhichAxis(app); %app.Ax0: axisNum=0;   app.Ax1: axisNum=1
            if isempty(axisNum), clearDrawLine(app); return; end % cursor not in any axis

            app.selectedAnchorID = [];
           % Reset cursor to arrow
            set(app.UIFigure, 'pointer','arrow');           

            %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  axisNum == 0
                Proc_WindowButtonUp_Ax0(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax0)

            %% --- IF cursor is within Ax1 (Audio):
            if  axisNum == 1 && ~isempty(app.AUDIO)
                Proc_WindowButtonUp_Ax1(app);
            end 
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~app.active, return; end
            axisNum = CurrentCursorWhichAxis(app); %app.Ax0: axisNum=0;   app.Ax1: axisNum=1
            if isempty(axisNum), return; end % cursor not in any axis

            update_axes_cursor_loc(app); % Update the Ed_CursorLoc.Value

            %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  axisNum == 0
                Proc_WindowButtonMotion_Ax0(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax0)     
            %% --- IF cursor is within Audio axis (app.Ax1):
            if  axisNum == 1 && ~isempty(app.AUDIO) && app.flag_mouseClicked
                Proc_WindowButtonMotion_Ax1(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax1)     
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
           if ~app.active, return; end
           if isBusy(app), return; end

            key = event.Key;  modifier = event.Modifier;
            switch key
                case 'rightarrow'
                    event.Source.UserData = 1;
                    Bn_ChangeFrameButtonPushed(app, event);
                case 'leftarrow'
                    event.Source.UserData = -1;
                    Bn_ChangeFrameButtonPushed(app, event);
                case 'pagedown'  
                    event.Source.UserData = app.Mn_ScrollPageForward.UserData;
                    Mn_ScrollPageMenuSelected(app, event);
                case 'pageup'
                    event.Source.UserData = app.Mn_ScrollPageBack.UserData;
                    Mn_ScrollPageMenuSelected(app, event);
            end
        end

        % Menu selected function: Mn_LoadVideo, Mn_LoadVideoAndContours, 
        % ...and 2 other components
        function Mn_LoadVideoPushed(app, event)
            % Load video function
            if isBusy(app), return; end

            if verLessThan('matlab', app.minMatlabVer)
                app.Msg1.Value = {'DeepEdge failed to start.', ...
                    ['Requires MATLAB ' app.minMatlabVerName ' or newer.']};
                return
            end
            if ~isempty(app.vObj)
                closeVideoSuccess = CloseVideoFile(app);
                if ~closeVideoSuccess, return; end % Aborted by user
            end
% app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
            loadMode = event.Source.UserData;
            [fl, selectionSuccess, vpath] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            app.LoadMode = loadMode;
            app.filelist = fl; app.filePath = vpath; 
            % Load first file
            success = LoadOneFileFromFilelist(app, 1);
            nFiles = numel(fl); 
            if nFiles > 1
                app.Msg1.Value = [num2str(nFiles) ' file(s) loaded.'];
            end
            SetStatus(app, 'ready');
        end

        % Callback function: Bn_FirstFrame, Bn_LastFrame, Bn_NextFrame, 
        % ...and 5 other components
        function Bn_ChangeFrameButtonPushed(app, event)
            %% Update NextNFrame and PreviousNFrame
%             app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
            %% Get UserData from calling button handle for +k frames
            if ~app.active, return; end;  if isBusy(app), return; end 
            SetStatus(app, 'busy');
            k = event.Source.UserData;
            iFrame = app.Ed_Frame.Value + k; % 'event.Source.UserData': move forward by how many frames
            if iFrame < 1, iFrame = 1;end
            if iFrame > app.nFrames, iFrame = app.nFrames;end
            if iFrame == app.Ed_Frame.Value, app.flag_busy = false; return; end 

%             try
                dispFrame(app, iFrame); updatePlotAnchors(app);   
                frameTime = app.Ed_Time.Value; xlm = app.Ax1.XLim; 
                app.AudioCursorTime = frameTime; 
                if isgraphics(app.handle_AudioSelection), 
                    plotUpdateAudioCursor(app, frameTime); 
                end
                if frameTime < xlm(1) || frameTime > xlm(2)
                    RefreshAudioAxis(app);
                end
%             catch
                SetStatus(app, 'ready');
%             end
            SetStatus(app, 'ready');
        end

        % Value changed function: Bn_CropButton
        function Bn_SelectCropRectButtonPushed(app, event)
            if ~app.active, app.Bn_CropButton.Value = 0; return; end
            if isBusy(app), return; end

            if ~app.Bn_CropButton.Value, return; end
            if ~isempty(app.handle_rect) && isgraphics(app_handle_rect)
                delete(app.handle_rect); app.handle_rect=[]; 
            end
            app=do_uncrop(app); app.flag_SelectCropRect = true;
           set(app.UIFigure, 'pointer','crosshair'); % Set cursor to crosshair
            app.Msg1.Value = 'Click the image and drag to make a rectangle to crop...';
            app.PreviousClickAnchorValue = app.Sw_ClickAnchor.Value;
            app.Sw_ClickAnchor.Value = 'Off'; 
        end

        % Button pushed function: Bn_Uncrop
        function Bn_UncropButtonPushed(app, event)
            if ~app.active, return; end
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn,return; end
            if app.flag_GetMppOn, return;end
            app.Bn_CropButton.Value = 0; app.flag_SelectCropRect = false;
            app.flag_SelectRectMouseDown = false;
            app.Msg1.Value = app.defaultMsg; 
            do_uncrop(app); %app.Sw_ClickAnchor.Value = 'On';
             set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
        end

        % Value changed function: Sw_ClickAnchor
        function Sw_ClickAnchorValueChanged(app, event)
%             value = app.Sw_ClickAnchor.Value;
            if app.flag_SelectCropRect || app.flag_SelectRectMouseDown
                app.Sw_ClickAnchor.Value = 'Off';
            end
        end

        % Value changed function: Slid_AnchorSize
        function Slid_AnchorSizeValueChanged(app, event)
            if ~app.active, return;end
            app.anchor_size = app.Slid_AnchorSize.Value;
            updatePlotAnchors(app); 
        end

        % Value changed function: Slid_AnchorAlpha
        function Slid_AnchorAlphaValueChanged(app, event)
             if ~app.active, return;end
            app.anchor_alpha = app.Slid_AnchorAlpha.Value/100; %Slider range:0:100
            updatePlotAnchors(app);         
        end

        % Callback function: Bn_DelAnchors, Mn_DeleteAllAnchorsCurrentFrame
        function Bn_DelAnchorsButtonPushed(app, event)
            if ~app.active, return; end
            if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end
            frame = app.Ed_Frame.Value; 
            if isempty(app.ANCHORS{frame}) && ...
                isempty(app.XY{frame}) && ...
                isempty(app.ENERGY{frame}) && ...
                isempty(app.METHOD{frame})
                % Nothing to delete
                return
            end
            app.ANCHORS{frame}=[]; app.XY{frame}=[]; app.ENERGY{frame}=[]; app.METHOD{frame}=[]; 
            app.flag_ifAnchorModifed = true;
            if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
            updatePlotAnchors(app);  
        end

        % Value changed function: Sw_ifInvert
        function Sw_ifInvertValueChanged(app, event)
            if ~app.active, ChangeBackToOriginalValue(app); return; end
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, ChangeBackToOriginalValue(app); return; end

            if strcmpi(app.Sw_ifInvert.Value, 'Invert'), app.ifInvert = true; else, app.ifInvert = false; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   

            function ChangeBackToOriginalValue(app)
                if app.ifInvert
                    app.Sw_ifInvert.Value = 'Invert';
                else
                    app.Sw_ifInvert.Value = 'Normal';
                end
            end
        end

        % Callback function: Bn_ApplyCurrentFrame, Mn_ApplyCurrentFrame
        function Bn_ApplyCurrentFramePushed(app, event)
            if ~app.active, return;  end
            if app.flag_GetMppOn, return;end
            if app.flag_SelectCropRect, app.Msg1.Value = 'Click the image and drag to make a rectangle to crop...'; return;end
            iFrame=app.Ed_Frame.Value; 
            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            %%
            SetStatus(app, 'busy');
            [anchors, energy, xy] = detectOneFrame(app, app.img, method, app.ANCHORS{iFrame});
            SetStatus(app, 'ready');
            if isempty(anchors),  return; end
            %%
            app.ANCHORS{iFrame} = anchors; app.XY{iFrame} = xy; app.ENERGY{iFrame} = energy; app.METHOD{iFrame} = method; 
            updatePlotAnchors(app); app.flag_ifAnchorModifed = true;
        end

        % Callback function: Bn_TrackFrames, Mn_Track, Mn_TrackNFrames, 
        % ...and 1 other component
        function StartTracking(app, event)
            app.Bn_StopTrack.Value = false;
             if app.flag_FrameTrackingOn, app.Bn_TrackFrames.Value = true; return; end % if "Track Frames" already pressed, then do nothing.

            if ~app.active, app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; return; end
            if app.flag_FileTrackingOn
                app.Msg1.Value = '"Track thru Files" is ON. Stop Tracking Files first.';
                app.Bn_TrackFrames.Value = false; return;
            end
            if isBusy(app), app.Bn_TrackFrames.Value = false; return; end

            currentFrame = app.Ed_Frame.Value;
            % IF it is the last frame: 
            if currentFrame == app.nFrames
                app.Msg1.Value = 'This is the last frame.'; 
                app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; return; 
            end

            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
                return;
            end

            app.Bn_TrackFrames.Value = true;  app.flag_FrameTrackingOn = true;

            k = event.Source.UserData; % Track next k frames
            StartFrame = currentFrame+1; EndFrame = currentFrame + k; 
            if EndFrame > app.nFrames, EndFrame = app.nFrames;end
              % Start tracking:  
             SetStatus(app, 'busy');  
%%
             [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method);
             % status = 0: error; 1: success; 2: user stopped.
             switch status
                 case 0
                    app.Msg1.Value = 'Error!';
                 case 1
                    app.Msg1.Value = sprintf('Successfully tracked %d frame(s)', EndFrame - StartFrame +1);
                 case 2
                     app.Msg1.Value = sprintf('User stopped. Tracked %d frame(s)', EndFrame - StartFrame +1);
             end
%%
            SetStatus(app, 'ready');  
            app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
        end

        % Menu selected function: Mn_ExpImgCrop, Mn_ExpImgRaw
        function Mn_ExpImg(app, event)
                if ~app.active; return; end   
                if isBusy(app), return; end

                videoFName = fullfile(app.vObj.Path, app.vObj.Name); 
               frame = app.Ed_Frame.Value;
               [videoPath, vFn, vExt]= fileparts(videoFName);
               ifCrop = event.Source.UserData.Crop;
               if ifCrop
                  im = imcrop(app.img, app.crop_rect); 
                  fn_suffix = '_Crop_Frame_';
               else
                   im = app.img; fn_suffix = '_Frame_';
               end
               outImgfn = [vFn, '_', strrep(vExt,'.',''), fn_suffix, num2str(frame) '.jpg'];
               outImgFName = fullfile(videoPath, outImgfn);
               imwrite(im, outImgFName);
        end

        % Menu selected function: Mn_Anchors2TSV_Crop, Mn_Anchors2TSV_Ori
        function Mn_Anchors2TSVSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end

            ifCrop = event.Source.UserData.Crop;
            % Write anchors of all frame to a text file. Overwrite existing file. 
            videoFName = app.vObj.Name; videoPath = app.vObj.Path; 
            [~, vFn, ~]= fileparts(videoFName);
            if ifCrop, suffix = '_Crop'; else,  suffix = ''; end
            outAnchorsfn = [vFn, suffix '.tsv']; 
            outAnchorsFName = fullfile(videoPath, outAnchorsfn);
            fid = fopen(outAnchorsFName,'wt'); 
            if fid < 0, uialert(app.UIFigure, ['Error attempting to write ' outAnchorsFName], 'Error'); return; end
            if ifCrop,  fprintf(fid,'Crop_para(Xmin, Ymin, Width, Height) : [%.2f, %.2f, %.2f, %.2f]\n', app.crop_rect(1), app.crop_rect(2), app.crop_rect(3), app.crop_rect(4)); end
            fprintf(fid,'FRAME\tTIME\tNOTE\tPOINT\tX\tY\n'); 
            for frame = 1 : numel(app.ANCHORS)
                anchors = app.ANCHORS{frame}; % ANCHORS{iFrame} : n x 3 {x, y, isUserInput} 
                if isempty(anchors), continue;end 
                anchors = round(anchors(:,1:2), 2); 
                if ifCrop, anchors = anchors - app.crop_rect(1:2); end
                for ci = 1 : size(anchors,1)
                    x =anchors(ci,1); y=anchors(ci,2);  fprintf(fid,'%d\t%f\t%s\t%d\t%.2f\t%.2f\n', frame, (frame-1)/app.vObj.FrameRate, '', ci, x, y);
                end
            end
            fclose(fid);  uialert(app.UIFigure, ['Successfully write to ' outAnchorsFName], 'Success','icon', 'success'); 
        end

        % Menu selected function: Mn_ExportMat, Mn_QuickSaveContours
        function Mn_ExportMatMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            silent = event.Source.UserData.Silent; 
            Export2Mat(app, silent);
        end

        % Value changed function: Ed_MaskSigma
        function Ed_MaskSigmaValueChanged(app, event)
            dispFrame(app, app.Ed_Frame.Value);
        end

        % Value changed function: Ck_UseMaskFromPreviousFrame
        function Ck_UseMaskFromPreviousFrameValueChanged(app, event)
            dispFrame(app, app.Ed_Frame.Value);
        end

        % Menu selected function: Mn_LoadContours
        function Mn_LoadContoursMenuSelected(app, event)
            if ~app.active
                 uialert(app.UIFigure,'Video not loaded yet! Load video first.','Video not loaded yet'); 
                return; 
            end
            if isBusy(app), return; end
            LoadContourStructUI(app);  
        end

        % Menu selected function: Mn_LoadModel
        function Mn_LoadModelMenuSelected(app, event)
            if isBusy(app), return; end

            % Display uigetfile dialog
            filterspec = {'*.mat'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            loadModel(app, fname);
        end

        % Callback function: Bn_StopTrack, Mn_StopTracking
        function StopTracking(app, event)
            if ~app.Bn_TrackFrames.Value && ~app.Bn_TrackFiles.Value
                app.Bn_StopTrack.Value = false;
            else
                app.Bn_StopTrack.Value = true; % press StopTrack button
                app.Bn_TrackFrames.Value = false; app.Bn_TrackFiles.Value = false;
            end
        end

        % Value changed function: Ed_NextN
        function Ed_NextNValueChanged(app, event)
            %% Update NextNFrame
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; 
            app.Mn_TrackNFrames.Text = sprintf('Track next %d frames', app.Ed_NextN.Value);
            app.Mn_TrackNFrames.UserData = app.Ed_NextN.Value;
        end

        % Value changed function: Ed_PreviousN
        function Ed_PreviousNValueChanged(app, event)
            %% Update PreviousNFrame
            app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
        end

        % Value changed function: Ed_GoToN
        function Ed_GoToNValueChanged(app, event)
            %% Update Bn_GoTo
            app.Bn_GoTo.UserData = app.Ed_GoToN.Value - app.Ed_Frame.Value;
        end

        % Menu selected function: Mn_LoadTextGrid
        function Mn_LoadTextGridMenuSelected(app, event)
            if ~app.active, return; end
            if isBusy(app), return; end

            if isempty(app.AUDIO),uialert(app.UIFigure,'No audio found.','Read error'); return; end
            % Display uigetfile dialog
            filterspec = {'*.TextGrid'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            success = loadTextGrid(app, fname);
            if success, drawTextGridIntervals(app); end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             if app.flag_FrameTrackingOn, return; end
%             if app.flag_GetMppOn, return;end
            ifAutosave = app.Ck_Autosave.Value; 
            msg = '';
            if ifAutosave && app.flag_ifAnchorModifed
                silent = true;
               Export2Mat(app, silent);              
            elseif app.flag_ifAnchorModifed
            % If there are unsaved changes, prompt the user to confirm:
                msg = 'Warning! There are unsaved changes to contours.';
            end

            msg = {msg, ' Close DeepEdge? '};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            switch selection
                case 'OK', app.delete(); case 'Cancel', return
            end
        end

        % Menu selected function: Mn_VideoInfo
        function Mn_VideoInfoMenuSelected(app, event)
            if isBusy(app), return; end
            v = app.vObj; title = 'Video information';
            if isempty(v)
                msg = 'No video loaded.';
            else
                msg = sprintf(...
                'Directory: \n%s\nFile:\n%s\n\nFrameRate: %.2f\nHeight: %.0f\nWidth: %.0f\nVideoFormat: %s\nDuration: %0.4f', ...
                v.Path, v.Name, v.FrameRate,v.Height, v.Width, v.VideoFormat, v.Duration);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info'); 
        end

        % Menu selected function: Mn_ModelInfo
        function Mn_ModelInfoMenuSelected(app, event)
            if isBusy(app), return; end
            m = app.net; title = 'Model information';
            if isempty(m)
                msg = 'No model loaded.';
            else
                msg = sprintf('%s%s\n\n%s\n', ...
                app.modelPath, filesep, app.modelname);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info');             
        end

        % Menu selected function: Mn_AboutDeepEdge
        function Mn_AboutDeepEdgeMenuSelected(app, event)
            if isBusy(app), return; end
            title = 'DeepEdge information';
            cInfo = 'Copyright (C) 2020-21 Wei-Rong Chen <chenw@haskins.yale.edu> This program is free software under GNU General Public License, version 3.This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. See the GNU General Public License for more details.'; 
            msg = sprintf('DeepEdge\nVersion: %s\nLatest Update: %s \n\n %s', ...
            app.Version, app.Update, cInfo);                
            uialert(app.UIFigure, msg, title, 'icon', 'info');              
        end

        % Menu selected function: Mn_ExportCropPara
        function Mn_ExportCropParamtersMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, [vFn '_crop_para.mat']);
            ExportCropPara(app, outFName , app.crop_rect);
        end

        % Menu selected function: Mn_PlaySelectedAudio
        function Mn_PlaySelectedAudioMenuSelected(app, event)
                 if ~app.active, return;end
                 if isBusy(app), return; end
                 if isempty(app.AUDIO), return; end
                 if isempty(app.handle_AudioSelection), return; end
                 if ~isgraphics(app.handle_AudioSelection), return; end
                 x = app.handle_AudioSelection.XData(1:2);
                 d = abs(x(2) - x(1));   t = [min(x), max(x)];    
                 if d < app.minAudioPlayDur, return; end
                try               
                     n = int64(floor(t*app.AUDIO.sr)+1); 
                    if n(1) < 1, n(1) = 1;end
                    if n(2) > size(app.AUDIO.s,1), n(2) = size(app.AUDIO.s,1); end
                    s = app.AUDIO.s(n(1) : n(2),1); sr  = app.AUDIO.sr;
                    app.AudPLAYER = audioplayer(s,sr);
			        play(app.AudPLAYER);
%                     sound(s, sr)
                 catch
                     app.Msg1.Value="Can't play audio!";
                 end
        end

        % Button pushed function: Bn_NextFile, Bn_PreviousFile
        function Bn_BrowseFileButtonPushed(app, event)
            % Browse/Load/Jump to next or previous file
            %% Get UserData from calling button handle for +k file
            if isBusy(app), return; end
            if isempty(app.filelist), return;end
            k = event.Source.UserData;% 'event.Source.UserData': +1 or -1
            fileInd = app.CurrentFileNumber + k; 
            if fileInd < 1 || fileInd > numel(app.filelist), return; end
            closeVideoSuccess = CloseVideoFile(app);
            if ~closeVideoSuccess, return; end % Aborted by user
            success = LoadOneFileFromFilelist(app, fileInd);
            SetStatus(app, 'ready');
        end

        % Menu selected function: Mn_ReselectTextGridTier
        function Mn_ReselectTextGridTierMenuSelected(app, event)
            if ~app.active || isempty(app.TextGrid) || numel(app.TextGrid) < 2, return; end
            if isBusy(app), return; end
            TierNames = {app.TextGrid.NAME};
             [ind, ifSelected] = listdlg('PromptString',{'Select only one tier ',...
                'in the TextGrid',''},'SelectionMode','single','ListString',TierNames);
            figure(app.UIFigure) % Get focus back on UIFigure
            if ~ifSelected || ind < 1, return; end
            app.TextGridTier = app.TextGrid(ind);
            app.WhichTextGridTierSelected = ind;
            drawTextGridIntervals(app); 
        end

        % Menu selected function: Mn_ShowSelectFromFileList
        function Mn_ShowSelectFromFileListMenuSelected(app, event)
            if isBusy(app), return; end
            if isempty(app.filelist), app.Msg1.Value = 'FileList is empty. Load video(s) first'; return; end
            prompt = {'Show/Select from current FileList.'};
            [fileInd, ifSelected] = listdlg('PromptString',prompt,...
                'SelectionMode','single','ListString', app.filelist, 'ListSize', [450, 300], 'InitialValue', app.CurrentFileNumber);
            figure(app.UIFigure) % Get focus back on UIFigure
            if ~ifSelected, return; end
            if fileInd == app.CurrentFileNumber, return;end

            closeVideoSuccess = CloseVideoFile(app);
            if ~closeVideoSuccess, return; end % Aborted by user

            success = LoadOneFileFromFilelist(app, fileInd);
            SetStatus(app, 'ready');
        end

        % Menu selected function: Mn_ApplySnake
        function Mn_ApplySnakeMenuSelected(app, event)
             if ~app.active, return; end
             if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end

             iFrame=app.Ed_Frame.Value; 
             method = 'SNAKE'; 
            [anchors, energy, xy] = detectOneFrame(app, app.img, method, app.ANCHORS{iFrame});
            if isempty(anchors),  return; end
            %%
            app.ANCHORS{iFrame} = anchors; app.XY{iFrame} = xy; app.ENERGY{iFrame} = energy; app.METHOD{iFrame} = method; 
            updatePlotAnchors(app);              
        end

        % Selection changed function: DetectMethodGroup
        function DetectMethodGroupSelectionChanged(app, event)
            app.DetectMethodGroup.UserData = app.DetectMethodGroup.SelectedObject.Text;
        end

        % Value changed function: Ed_TrackingDelayMs
        function Ed_TrackingDelayMsValueChanged(app, event)
            app.TrackingDelayMs = app.Ed_TrackingDelayMs.Value;
        end

        % Menu selected function: Mn_DeleteAllAnchorsinAllFrames
        function Mn_DeleteAllAnchorsinAllFramesMenuSelected(app, event)
            if ~app.active, return; end
            if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end
            msg = {'Delete All Anchors/Contours in ALL frames! ', 'Are you sure? '};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            if strcmpi(selection, 'Cancel'), return;end

            app.ANCHORS = cell(app.nFrames,1); app.XY = cell(app.nFrames,1); 
            app.ENERGY = cell(app.nFrames,1); app.TIME = cell(app.nFrames,1); app.METHOD = cell(app.nFrames,1); 

            app.flag_ifAnchorModifed = true;
            if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
            updatePlotAnchors(app);              
        end

        % Value changed function: Ck_Autosave
        function Ck_AutosaveValueChanged(app, event)
            if app.Ck_Autosave.Value
                app.Msg1.Value  = {'Autosave ON', ...
                    'Warning: Existing .mat file will be overwritten!'};
            else 
                app.Msg1.Value = ''; 
            end
        end

        % Button pushed function: Bn_SnakeDefault
        function Bn_SnakeDefaultButtonPushed(app, event)
            app.snakePara = app.snakeDefaultPara; 
            app.Ed_SnakeSigma.Value = app.snakeDefaultPara.Sigma;
            app.Ed_SnakeDelta.Value = app.snakeDefaultPara.Delta;
            app.Slid_SnakeAlpha.Value = app.snakeDefaultPara.Alpha;
            app.Ed_SnakeAlpha.Value = app.snakeDefaultPara.Alpha;
            app.Slid_SnakeLambda1.Value = app.snakeDefaultPara.Lambda1;
            app.Ed_SnakeLambda1.Value = app.snakeDefaultPara.Lambda1;
            app.Ed_SnakeBandPenalty.Value = app.snakeDefaultPara.BandPenalty;
        end

        % Value changed function: Ed_SnakeBandPenalty
        function Ed_SnakeBandPenaltyValueChanged(app, event)
            app.snakePara.BandPenalty = app.Ed_SnakeBandPenalty.Value;
        end

        % Value changed function: Slid_SnakeLambda1
        function Slid_SnakeLambda1ValueChanged(app, event)
            app.snakePara.Lambda1 = app.Slid_SnakeLambda1.Value;  
            app.Ed_SnakeLambda1.Value = app.Slid_SnakeLambda1.Value;  
        end

        % Value changed function: Slid_SnakeAlpha
        function Slid_SnakeAlphaValueChanged(app, event)
            app.snakePara.Alpha = app.Slid_SnakeAlpha.Value;
            app.Ed_SnakeAlpha.Value = app.Slid_SnakeAlpha.Value;
        end

        % Value changed function: Ed_SnakeSigma
        function Ed_SnakeSigmaValueChanged(app, event)
            app.snakePara.Sigma = app.Ed_SnakeSigma.Value;
        end

        % Value changed function: Ed_SnakeDelta
        function Ed_SnakeDeltaValueChanged(app, event)
            app.snakePara.Delta = app.Ed_SnakeDelta.Value;   
        end

        % Menu selected function: Mn_LoadAudio
        function Mn_LoadAudioMenuSelected(app, event)
            if ~app.active, return; end
            if isBusy(app), return; end
            filterspec = {'*.wav;*.flac;*.mp3;*.ogg;*.aiff;*.aifc;*.aif;*.opus;*.mp4;*.m4a','All Audio Files'};
            [file, apath] = uigetfile(filterspec, 'multiselect', 'off');
            figure(app.UIFigure); % Get focus back to main figure
             if isequal(file,0),  return; end 
             FName = fullfile(apath, file);
             success = Read1AudioFile(app, FName);
        end

        % Menu selected function: Mn_AddAudioOffset
        function Mn_AddAudioOffsetMenuSelected(app, event)
%             if ~app.active, return; end
            if isBusy(app), return; end

            defaultAnswer = {sprintf('%0.1f', app.audioOffset*1000)};
            answer = inputdlg('Enter audio offset in millisecond:',...
             'Audio Delay in ms', [1 50], defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
             if isempty(answer),  return; end
            answer = answer{1};
            if isempty(answer),  return; end
            delayMs = str2num(answer); 
             if isempty(answer),  return; end
             delay = delayMs /1000; 
             app.audioOffset = delay;
             if isempty(app.AUDIO), return; end
             %% if there is AUDIO:
             if ~isempty(app.AUDIO),  Read1AudioFile(app, app.AUDIO.FileName); end
             if ~isempty(app.TextGrid)
                 success = loadTextGrid(app, app.TextGridFilename);
                if success, drawTextGridIntervals(app); end
             end
        end

        % Value changed function: Sw_ContourVisible
        function Sw_ContourVisibleValueChanged(app, event)
            value = app.Sw_ContourVisible.Value; 
            if ~strcmpi(value, app.contour_visible)
                app.contour_visible = app.Sw_ContourVisible.Value;
                updatePlotAnchors(app);
            end
        end

        % Menu selected function: Mn_FindMPP
        function Mn_FindMPPMenuSelected(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end
            app.flag_GetMppOn = true;
            app.Msg1.Value = { ...
                'CLICK & DRAG to select 1 cm interval ', 'to calculate MPP.'};
            set(app.UIFigure, 'pointer','crosshair'); % Set cursor to crosshair
        end

        % Menu selected function: Mn_ZoomIn, Mn_ZoomOut
        function Mn_AudioZoomMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            zoomScale =  event.Source.UserData;
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);  
            newDur = currentDur * zoomScale; 
            center = mean(xlm); 
            left = center - newDur/2; right = center + newDur/2;
            RefreshAudioAxis(app, [left, right]);
        end

        % Menu selected function: Mn_ScrollPageBack, Mn_ScrollPageForward
        function Mn_ScrollPageMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            % Shift auido view to current view duration * scrollRate
            % e.g., if scrollRate = 0.7, shift audio view to the right by
            % 70% of current view duration.
            scrollRate =  event.Source.UserData; 
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);         
            scroll = currentDur * scrollRate; 
            left = xlm(1) + scroll; right = xlm(2) + scroll;
            RefreshAudioAxis(app, [left, right]);
        end

        % Menu selected function: Mn_ZoomToAudioCursor, Mn_ZoomToSelection
        function Mn_ZoomToAudioCursorMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            if isempty(app.AudioCursorTime), return;end
            audioSel = app.handle_AudioSelection.XData(1:2);
            audioSelDur = audioSel(2) - audioSel(1);
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);  
            if audioSelDur > app.minAudioDisplayDur ...
                    && audioSelDur > app.minAudioSelectDur
                newDur = audioSelDur; cursorT = mean(audioSel); 
            else
                newDur = currentDur/2; cursorT = app.AudioCursorTime; 
            end
            left = cursorT - newDur/2; right = cursorT + newDur/2;
            RefreshAudioAxis(app, [left, right]);          
        end

        % Menu selected function: Mn_ViewAll
        function Mn_ViewAllMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return; end 
            audioDur = app.AUDIO.Dur; 
            RefreshAudioAxis(app,[0, audioDur]);            
        end

        % Button pushed function: Bn_Diagnose
        function Bn_DiagnoseButtonPushed(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end 
            SetStatus(app, 'busy')
            try
                app.diagnosticMatrix = app.DiagnoseAnchors(app.ANCHORS, app.ENERGY); 
                RunDiagnosticFilters(app);
                plotUpdateDiagnosis(app);
            catch
                 SetStatus(app, 'ready'); return
            end
             app.UIBadContourTable.Data = app.BadContourFrameIndex;
            SetStatus(app, 'ready')
        end

        % Button pushed function: Bn_GoTo
        function Bn_GoToButtonPushed(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end 
            iFrame = app.Ed_GoToN.Value; % 'event.Source.UserData': move forward by how many frames
            currentFrame = app.Ed_Frame.Value; 
            k = iFrame - currentFrame; 
            event.Source.UserData = k;
            Bn_ChangeFrameButtonPushed(app, event);
        end

        % Cell selection callback: UIBadContourTable
        function UIBadContourTableCellSelection(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end
            if isempty(app.BadContourFrameIndex), return;end
            ind = event.Indices(1);
            iFrame = app.BadContourFrameIndex(ind);
            currentFrame = app.Ed_Frame.Value; 
            k = iFrame - currentFrame; 
            event.Source.UserData = k;
            Bn_ChangeFrameButtonPushed(app, event);
        end

        % Value changed function: Ck_ShowDiagOnAudio
        function Ck_ShowDiagOnAudioValueChanged(app, event)
            value = app.Ck_ShowDiagOnAudio.Value;
            if value
                app.diagnosis_visible = 'on';
            else
                app.diagnosis_visible = 'off';
            end
            if ~isempty(app.handle_diagnosis) && isgraphics(app.handle_diagnosis)
                app.handle_diagnosis.Visible = app.diagnosis_visible;
            end
        end

        % Value changed function: Ed_savingFreq
        function Ed_savingFreqValueChanged(app, event)
            value = app.Ed_savingFreq.Value;
            tooltip = sprintf('AutoSave contours when tracking every %d frames, or when switching/closing video file.', value);
            app.Ed_savingFreq.Tooltip = tooltip;
            app.Ck_Autosave.Tooltip = tooltip;
        end

        % Selection changed function: BnG_EnhanceContrast
        function BnG_EnhanceContrastSelectionChanged(app, event)
%             contrastMethod = app.DBnG_EnhanceContrast.SelectedObject.Text;
            if ~app.active, return; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   
        end

        % Value changed function: Bn_TrackFiles
        function Bn_TrackFilesValueChanged(app, event)
             app.Bn_StopTrack.Value = false;
             if app.flag_FileTrackingOn, app.Bn_TrackFiles.Value = true; return; end % if "Track Files" already pressed, then toggel the button back and do nothing.

            if ~app.active, app.Bn_TrackFiles.Value = false; app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false; return; end
            if app.flag_FrameTrackingOn
                app.Msg1.Value = '"Track thru frames" is ON. Stop tracking frames first.';
                app.Bn_TrackFiles.Value = false; return;
            end
            
            if isBusy(app), app.Bn_TrackFiles.Value = false; return; end
            if isempty(app.filelist), 
                app.Msg1.Value = 'No file(s) loaded. Load files first.';
                app.Bn_TrackFiles.Value = false; return;
            end
   
            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'

            if strcmpi(method, 'SNAKE')
                 app.Msg1.Value = 'SNAKE only tracks on single file. Choose NN or NN+SNAKE.';
                 app.Bn_TrackFiles.Value = false; return;               
            end

            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                app.Bn_TrackFiles.Value = false; app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
                return;
            end 

            startFileInd = app.CurrentFileNumber;   totalFileNumber = numel(app.filelist);
            app.Bn_TrackFiles.Value = true;  app.flag_FileTrackingOn = true;
              % Start tracking:  
            for iFile = startFileInd : totalFileNumber
                if iFile == startFileInd % First (current) file:
                    StartFrame = app.Ed_Frame.Value;               
                else 
                    StartFrame = 1; 
                    success = LoadOneFileFromFilelist(app, iFile);
                    if ~success, continue; end
                end
                SetStatus(app, 'busy');  
                EndFrame = app.nFrames; 
                if StartFrame > EndFrame, continue; end % If it is the last frame, continue to the next file. 
                 [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method);
                 % status = 0: error; 1: success; 2: user stopped.
                 switch status
                     case 0
                        app.Msg1.Value = 'Error!';
                     case 1
                        silent = true; Export2Mat(app, silent);
                         app.Msg1.Value = sprintf('Tracked 1 file. (Saved %d contours/frames)', EndFrame - StartFrame +1);
                     case 2
                         app.Msg1.Value = sprintf('User stopped. Tracked %d file(s). Contours in this file NOT saved yet.', iFile - startFileInd);
                         app.Bn_TrackFiles.Value = false; app.flag_FileTrackingOn = false;    
                         break
                 end
            end %for iFile = startFileInd : totalFileNumber
            app.Msg1.Value = sprintf('Tracked %d file(s).', iFile - startFileInd +1);
            app.Bn_TrackFiles.Value = false; app.flag_FileTrackingOn = false;          
            SetStatus(app, 'ready');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DeepEdge';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Mn_LoadModel = uimenu(app.FileMenu);
            app.Mn_LoadModel.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadModelMenuSelected, true);
            app.Mn_LoadModel.Text = 'Load model';
            app.Mn_LoadVideo = uimenu(app.FileMenu);
            app.Mn_LoadVideo.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideo.Text = 'Load video(s)';
            app.Mn_LoadContours = uimenu(app.FileMenu);
            app.Mn_LoadContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadContoursMenuSelected, true);
            app.Mn_LoadContours.Text = 'Load contours';
            app.Mn_LoadTextGrid = uimenu(app.FileMenu);
            app.Mn_LoadTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadTextGridMenuSelected, true);
            app.Mn_LoadTextGrid.Text = 'Load TextGrid';
            app.Mn_LoadVideoAndContours = uimenu(app.FileMenu);
            app.Mn_LoadVideoAndContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndContours.Text = 'Load video(s) + contours';
            app.Mn_LoadVideoAndTextGrid = uimenu(app.FileMenu);
            app.Mn_LoadVideoAndTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndTextGrid.Text = 'Load video(s) + TextGrid';
            app.Mn_LoadVideoAndContoursAndTextGrid = uimenu(app.FileMenu);
            app.Mn_LoadVideoAndContoursAndTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndContoursAndTextGrid.Text = 'Load video(s) + contours + TextGrid';
            app.Mn_LoadAudio = uimenu(app.FileMenu);
            app.Mn_LoadAudio.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadAudioMenuSelected, true);
            app.Mn_LoadAudio.Text = 'Load audio';
            app.Mn_ReselectTextGridTier = uimenu(app.FileMenu);
            app.Mn_ReselectTextGridTier.MenuSelectedFcn = createCallbackFcn(app, @Mn_ReselectTextGridTierMenuSelected, true);
            app.Mn_ReselectTextGridTier.Text = 'Re-select TextGrid tier';
            app.Mn_ShowSelectFromFileList = uimenu(app.FileMenu);
            app.Mn_ShowSelectFromFileList.MenuSelectedFcn = createCallbackFcn(app, @Mn_ShowSelectFromFileListMenuSelected, true);
            app.Mn_ShowSelectFromFileList.Text = 'Show/Select from loaded FileList';
            app.Mn_Export = uimenu(app.UIFigure);
            app.Mn_Export.Text = 'Export';
            app.Mn_ExportCurrentFrame = uimenu(app.Mn_Export);
            app.Mn_ExportCurrentFrame.Text = 'Current frame image';
            app.Mn_ExpImgRaw = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgRaw.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExpImg, true);
            app.Mn_ExpImgRaw.Text = 'raw';
            app.Mn_ExpImgCrop = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgCrop.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExpImg, true);
            app.Mn_ExpImgCrop.Text = 'cropped';
            app.Mn_Anchors2TSV = uimenu(app.Mn_Export);
            app.Mn_Anchors2TSV.Text = 'Export anchors to .TSV';
            app.Mn_Anchors2TSV_Ori = uimenu(app.Mn_Anchors2TSV);
            app.Mn_Anchors2TSV_Ori.MenuSelectedFcn = createCallbackFcn(app, @Mn_Anchors2TSVSelected, true);
            app.Mn_Anchors2TSV_Ori.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the original (uncropped) image.'};
            app.Mn_Anchors2TSV_Ori.Text = 'original cartesian';
            app.Mn_Anchors2TSV_Crop = uimenu(app.Mn_Anchors2TSV);
            app.Mn_Anchors2TSV_Crop.MenuSelectedFcn = createCallbackFcn(app, @Mn_Anchors2TSVSelected, true);
            app.Mn_Anchors2TSV_Crop.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the cropped mage..'};
            app.Mn_Anchors2TSV_Crop.Text = 'cropped cartesian';
            app.Mn_ExportMat = uimenu(app.Mn_Export);
            app.Mn_ExportMat.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMatMenuSelected, true);
            app.Mn_ExportMat.Tooltip = {'Export results to MATLAB structure file (.mat)'};
            app.Mn_ExportMat.Text = 'Export contours to .MAT file';
            app.Mn_QuickSaveContours = uimenu(app.Mn_Export);
            app.Mn_QuickSaveContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMatMenuSelected, true);
            app.Mn_QuickSaveContours.Tooltip = {'Save & Overwrite existing MAT file'};
            app.Mn_QuickSaveContours.Text = 'Quick Export contours to .MAT file';
            app.Mn_ExportCropPara = uimenu(app.Mn_Export);
            app.Mn_ExportCropPara.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportCropParamtersMenuSelected, true);
            app.Mn_ExportCropPara.Text = 'Export cropping paramters';
            app.CommandMenu = uimenu(app.UIFigure);
            app.CommandMenu.Text = 'Command';
            app.Mn_PreviousFrame = uimenu(app.CommandMenu);
            app.Mn_PreviousFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Mn_PreviousFrame.Accelerator = 'B';
            app.Mn_PreviousFrame.Text = 'Previous frame';
            app.Mn_NextFrame = uimenu(app.CommandMenu);
            app.Mn_NextFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Mn_NextFrame.Accelerator = 'F';
            app.Mn_NextFrame.Text = 'Next frame';
            app.Mn_ApplyCurrentFrame = uimenu(app.CommandMenu);
            app.Mn_ApplyCurrentFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Mn_ApplyCurrentFrame.Accelerator = 'M';
            app.Mn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Mn_Track = uimenu(app.CommandMenu);
            app.Mn_Track.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_Track.Accelerator = 'T';
            app.Mn_Track.Text = 'Track continuously';
            app.Mn_StopTracking = uimenu(app.CommandMenu);
            app.Mn_StopTracking.MenuSelectedFcn = createCallbackFcn(app, @StopTracking, true);
            app.Mn_StopTracking.Accelerator = 'X';
            app.Mn_StopTracking.Text = 'Stop tracking';
            app.Mn_DeleteAllAnchorsCurrentFrame = uimenu(app.CommandMenu);
            app.Mn_DeleteAllAnchorsCurrentFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_DelAnchorsButtonPushed, true);
            app.Mn_DeleteAllAnchorsCurrentFrame.Accelerator = 'Y';
            app.Mn_DeleteAllAnchorsCurrentFrame.Text = 'Delete all anchors @current frame';
            app.Mn_TrackNextFrame = uimenu(app.CommandMenu);
            app.Mn_TrackNextFrame.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_TrackNextFrame.Accelerator = '1';
            app.Mn_TrackNextFrame.Text = 'Track next frame';
            app.Mn_TrackNFrames = uimenu(app.CommandMenu);
            app.Mn_TrackNFrames.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_TrackNFrames.Accelerator = '2';
            app.Mn_TrackNFrames.Text = 'Track next N frames';
            app.Mn_ApplySnake = uimenu(app.CommandMenu);
            app.Mn_ApplySnake.MenuSelectedFcn = createCallbackFcn(app, @Mn_ApplySnakeMenuSelected, true);
            app.Mn_ApplySnake.Accelerator = 'N';
            app.Mn_ApplySnake.Text = 'Apply Snake @current frame';
            app.Mn_DeleteAllAnchorsinAllFrames = uimenu(app.CommandMenu);
            app.Mn_DeleteAllAnchorsinAllFrames.MenuSelectedFcn = createCallbackFcn(app, @Mn_DeleteAllAnchorsinAllFramesMenuSelected, true);
            app.Mn_DeleteAllAnchorsinAllFrames.Text = 'Reset/Delete All Anchors in All Frames';
            app.Mn_FindMPP = uimenu(app.CommandMenu);
            app.Mn_FindMPP.MenuSelectedFcn = createCallbackFcn(app, @Mn_FindMPPMenuSelected, true);
            app.Mn_FindMPP.Text = 'Measure 1 cm (Find mm/pixel)';
            app.AudioMenu = uimenu(app.UIFigure);
            app.AudioMenu.Text = 'Audio';
            app.Mn_AddAudioOffset = uimenu(app.AudioMenu);
            app.Mn_AddAudioOffset.MenuSelectedFcn = createCallbackFcn(app, @Mn_AddAudioOffsetMenuSelected, true);
            app.Mn_AddAudioOffset.Text = 'Add/Reset audio offset';
            app.Mn_ViewAll = uimenu(app.AudioMenu);
            app.Mn_ViewAll.MenuSelectedFcn = createCallbackFcn(app, @Mn_ViewAllMenuSelected, true);
            app.Mn_ViewAll.Accelerator = 'a';
            app.Mn_ViewAll.Text = 'View all';
            app.Mn_ZoomIn = uimenu(app.AudioMenu);
            app.Mn_ZoomIn.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioZoomMenuSelected, true);
            app.Mn_ZoomIn.Accelerator = 'i';
            app.Mn_ZoomIn.Text = 'Zoom in';
            app.Mn_ZoomOut = uimenu(app.AudioMenu);
            app.Mn_ZoomOut.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioZoomMenuSelected, true);
            app.Mn_ZoomOut.Accelerator = 'o';
            app.Mn_ZoomOut.Text = 'Zoom out';
            app.Mn_ScrollPageBack = uimenu(app.AudioMenu);
            app.Mn_ScrollPageBack.MenuSelectedFcn = createCallbackFcn(app, @Mn_ScrollPageMenuSelected, true);
            app.Mn_ScrollPageBack.Text = 'Scroll page back (PageUp)';
            app.Mn_ScrollPageForward = uimenu(app.AudioMenu);
            app.Mn_ScrollPageForward.MenuSelectedFcn = createCallbackFcn(app, @Mn_ScrollPageMenuSelected, true);
            app.Mn_ScrollPageForward.Text = 'Scroll page forward (PageDown)';
            app.Mn_ZoomToAudioCursor = uimenu(app.AudioMenu);
            app.Mn_ZoomToAudioCursor.MenuSelectedFcn = createCallbackFcn(app, @Mn_ZoomToAudioCursorMenuSelected, true);
            app.Mn_ZoomToAudioCursor.Accelerator = 'c';
            app.Mn_ZoomToAudioCursor.Text = 'Zoom in to audio cursor';
            app.Mn_ZoomToSelection = uimenu(app.AudioMenu);
            app.Mn_ZoomToSelection.MenuSelectedFcn = createCallbackFcn(app, @Mn_ZoomToAudioCursorMenuSelected, true);
            app.Mn_ZoomToSelection.Accelerator = 'c';
            app.Mn_ZoomToSelection.Text = 'Zoom to selection';
            app.Mn_PlaySelectedAudio = uimenu(app.AudioMenu);
            app.Mn_PlaySelectedAudio.MenuSelectedFcn = createCallbackFcn(app, @Mn_PlaySelectedAudioMenuSelected, true);
            app.Mn_PlaySelectedAudio.Accelerator = 'p';
            app.Mn_PlaySelectedAudio.Text = 'Play selected audio';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Mn_VideoInfo = uimenu(app.InfoMenu);
            app.Mn_VideoInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_VideoInfoMenuSelected, true);
            app.Mn_VideoInfo.Text = 'Video Information';
            app.Mn_ModelInfo = uimenu(app.InfoMenu);
            app.Mn_ModelInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_ModelInfoMenuSelected, true);
            app.Mn_ModelInfo.Text = 'Model Information';
            app.Mn_AboutDeepEdge = uimenu(app.InfoMenu);
            app.Mn_AboutDeepEdge.MenuSelectedFcn = createCallbackFcn(app, @Mn_AboutDeepEdgeMenuSelected, true);
            app.Mn_AboutDeepEdge.Text = 'About DeepEdge';
            app.Ax0 = uiaxes(app.UIFigure);
            app.Ax0.XLim = [0 1];
            app.Ax0.ZLim = [0 1];
            app.Ax0.XTick = [];
            app.Ax0.XTickLabel = {'[ ]'};
            app.Ax0.YTick = [];
            app.Ax1 = uiaxes(app.UIFigure);
            app.Ax1.XTick = [];
            app.Ax1.XTickLabel = '';
            app.Ax1.YTick = [];
            app.Ax1.ZTick = [];
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.Ed_CurrentFile = uieditfield(app.UIFigure, 'text');
            app.Ed_CurrentFile.Editable = 'off';
            app.Ed_CurrentFile.Tooltip = {'Current filename'};
            app.Ed_FileInd = uieditfield(app.UIFigure, 'text');
            app.Ed_FileInd.Editable = 'off';
            app.Ed_FileInd.Enable = 'off';
            app.Ed_FileInd.Value = '0/0';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame#:';
            app.Ed_Frame = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Frame.Limits = [0 Inf];
            app.Ed_Frame.ValueDisplayFormat = '%d';
            app.Ed_Frame.Editable = 'off';
            app.Ed_TotalFrames = uieditfield(app.UIFigure, 'text');
            app.Ed_TotalFrames.Editable = 'off';
            app.Ed_TotalFrames.Enable = 'off';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = '';
            app.Lp_Status = uilamp(app.UIFigure);
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time (s): ';
            app.Ed_Time = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Time.Limits = [0 Inf];
            app.Ed_Time.ValueDisplayFormat = '%0.4f';
            app.Ed_Time.Editable = 'off';
            app.Ed_TotalTime = uieditfield(app.UIFigure, 'numeric');
            app.Ed_TotalTime.Limits = [0 Inf];
            app.Ed_TotalTime.ValueDisplayFormat = '%.3g';
            app.Ed_TotalTime.Editable = 'off';
            app.Ed_TotalTime.Enable = 'off';
            app.Tabs = uitabgroup(app.UIFigure);
            app.Tab_Config = uitab(app.Tabs);
            app.Tab_Config.Tooltip = {''};
            app.Tab_Config.Title = 'Config.';
            app.Bn_CropButton = uibutton(app.Tab_Config, 'state');
            app.Bn_CropButton.ValueChangedFcn = createCallbackFcn(app, @Bn_SelectCropRectButtonPushed, true);
            app.Bn_CropButton.Text = 'Crop';
            app.Bn_Uncrop = uibutton(app.Tab_Config, 'push');
            app.Bn_Uncrop.ButtonPushedFcn = createCallbackFcn(app, @Bn_UncropButtonPushed, true);
            app.Bn_Uncrop.Text = 'Uncrop';
            app.Ed_CropRect = uieditfield(app.Tab_Config, 'text');
            app.Ed_CropRect.Editable = 'off';
            app.Ed_CropRect.Enable = 'off';
            app.CropParaHelpMsg = uieditfield(app.Tab_Config, 'text');
            app.CropParaHelpMsg.Editable = 'off';
            app.CropParaHelpMsg.Enable = 'off';
            app.CropParaHelpMsg.Value = '(left, top, width, height)';
            app.Sw_ifInvert = uiswitch(app.Tab_Config, 'slider');
            app.Sw_ifInvert.Items = {'Normal', 'Invert'};
            app.Sw_ifInvert.ValueChangedFcn = createCallbackFcn(app, @Sw_ifInvertValueChanged, true);
            app.Sw_ifInvert.Value = 'Normal';
            app.Pn_SnakeParas = uipanel(app.Tab_Config);
            app.Pn_SnakeParas.Title = 'SNAKE parameters:';
            app.SigmaLabel = uilabel(app.Pn_SnakeParas);
            app.SigmaLabel.Text = 'Sigma:';
            app.Ed_SnakeSigma = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeSigma.Limits = [0 50];
            app.Ed_SnakeSigma.ValueDisplayFormat = '%d';
            app.Ed_SnakeSigma.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeSigmaValueChanged, true);
            app.Ed_SnakeSigma.Tooltip = {'Image gradient smoothness'};
            app.Ed_SnakeSigma.Value = 5;
            app.DeltaLabel = uilabel(app.Pn_SnakeParas);
            app.DeltaLabel.Text = 'Delta:';
            app.Ed_SnakeDelta = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeDelta.Limits = [0 50];
            app.Ed_SnakeDelta.ValueDisplayFormat = '%d';
            app.Ed_SnakeDelta.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeDeltaValueChanged, true);
            app.Ed_SnakeDelta.Tooltip = {'Snake local search margin'};
            app.Ed_SnakeDelta.Value = 2;
            app.BandPenaltyLabel = uilabel(app.Pn_SnakeParas);
            app.BandPenaltyLabel.Text = 'Band Penalty:';
            app.Ed_SnakeBandPenalty = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeBandPenalty.Limits = [0 20];
            app.Ed_SnakeBandPenalty.ValueDisplayFormat = '%d';
            app.Ed_SnakeBandPenalty.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeBandPenaltyValueChanged, true);
            app.Ed_SnakeBandPenalty.Value = 2;
            app.Lambda1Label_2 = uilabel(app.Pn_SnakeParas);
            app.Lambda1Label_2.Text = 'Alpha:';
            app.Slid_SnakeAlpha = uislider(app.Pn_SnakeParas);
            app.Slid_SnakeAlpha.Limits = [0 1];
            app.Slid_SnakeAlpha.MajorTicks = [];
            app.Slid_SnakeAlpha.ValueChangedFcn = createCallbackFcn(app, @Slid_SnakeAlphaValueChanged, true);
            app.Slid_SnakeAlpha.MinorTicks = [];
            app.Slid_SnakeAlpha.Tooltip = {'Internal energy weight'};
            app.Slid_SnakeAlpha.Value = 0.7;
            app.Ed_SnakeAlpha = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeAlpha.Limits = [0 1];
            app.Ed_SnakeAlpha.ValueDisplayFormat = '%0.2g';
            app.Ed_SnakeAlpha.Editable = 'off';
            app.Ed_SnakeAlpha.Enable = 'off';
            app.Ed_SnakeAlpha.Tooltip = {'Internal energy weight.  Beta (external energy weight) = 1 - Alpha'};
            app.Ed_SnakeAlpha.Value = 0.7;
            app.Lambda1Label = uilabel(app.Pn_SnakeParas);
            app.Lambda1Label.Text = 'Lambda1:';
            app.Slid_SnakeLambda1 = uislider(app.Pn_SnakeParas);
            app.Slid_SnakeLambda1.Limits = [0 1];
            app.Slid_SnakeLambda1.MajorTicks = [];
            app.Slid_SnakeLambda1.ValueChangedFcn = createCallbackFcn(app, @Slid_SnakeLambda1ValueChanged, true);
            app.Slid_SnakeLambda1.MinorTicks = [];
            app.Slid_SnakeLambda1.Tooltip = {'Contour roughness weight'};
            app.Slid_SnakeLambda1.Value = 0.7;
            app.Ed_SnakeLambda1 = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeLambda1.Limits = [0 1];
            app.Ed_SnakeLambda1.ValueDisplayFormat = '%0.2g';
            app.Ed_SnakeLambda1.Editable = 'off';
            app.Ed_SnakeLambda1.Enable = 'off';
            app.Ed_SnakeLambda1.Tooltip = {'smoothness weight.  Lambda2 (evenness weight) = 1-Lambda1  '};
            app.Ed_SnakeLambda1.Value = 0.7;
            app.Bn_SnakeDefault = uibutton(app.Pn_SnakeParas, 'push');
            app.Bn_SnakeDefault.ButtonPushedFcn = createCallbackFcn(app, @Bn_SnakeDefaultButtonPushed, true);
            app.Bn_SnakeDefault.Text = 'Set SNAKE default';
            app.everyEditFieldLabel = uilabel(app.Tab_Config);
            app.everyEditFieldLabel.Text = 'every';
            app.Ed_savingFreq = uieditfield(app.Tab_Config, 'numeric');
            app.Ed_savingFreq.Limits = [1 100000];
            app.Ed_savingFreq.RoundFractionalValues = 'on';
            app.Ed_savingFreq.ValueDisplayFormat = '%d';
            app.Ed_savingFreq.ValueChangedFcn = createCallbackFcn(app, @Ed_savingFreqValueChanged, true);
            app.Ed_savingFreq.Tooltip = {''};
            app.Ed_savingFreq.Value = 2000;
            app.Ck_Autosave = uicheckbox(app.Tab_Config);
            app.Ck_Autosave.ValueChangedFcn = createCallbackFcn(app, @Ck_AutosaveValueChanged, true);
            app.Ck_Autosave.Tooltip = {''};
            app.Ck_Autosave.Text = 'AutoSave';
            app.Txt_tracked_frames = uilabel(app.Tab_Config);
            app.Txt_tracked_frames.Text = 'tracked frames';
            app.BnG_EnhanceContrast = uibuttongroup(app.Tab_Config);
            app.BnG_EnhanceContrast.SelectionChangedFcn = createCallbackFcn(app, @BnG_EnhanceContrastSelectionChanged, true);
            app.BnG_EnhanceContrast.Title = 'Enhance contrast:';
            app.Bn_NoEnhance = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_NoEnhance.Text = 'None';
            app.Bn_NoEnhance.Value = true;
            app.Bn_Adjust = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_Adjust.Tooltip = {'Saturates top and bottom 1% of all pixels. See ''imadjust.m'''};
            app.Bn_Adjust.Text = 'Adjust';
            app.Bn_HistEq = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_HistEq.Tooltip = {'histogram equalization. See ''histeq.m'''};
            app.Bn_HistEq.Text = 'HistEq';
            app.Bn_AdaptHistEq = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_AdaptHistEq.Tooltip = {'contrast-limited adaptive histogram equalization. See ''adapthisteq.m'''};
            app.Bn_AdaptHistEq.Text = 'AdaptHistEq';
            app.AnchorTab = uitab(app.Tabs);
            app.AnchorTab.Title = 'Anchor';
            app.ClickanchorsSwitchLabel = uilabel(app.AnchorTab);
            app.ClickanchorsSwitchLabel.Text = 'Click anchors:';
            app.Sw_ClickAnchor = uiswitch(app.AnchorTab, 'slider');
            app.Sw_ClickAnchor.ValueChangedFcn = createCallbackFcn(app, @Sw_ClickAnchorValueChanged, true);
            app.Sw_ClickAnchor.Value = 'On';
            app.AnchorsizeSliderLabel = uilabel(app.AnchorTab);
            app.AnchorsizeSliderLabel.Text = 'Anchor size:';
            app.Slid_AnchorSize = uislider(app.AnchorTab);
            app.Slid_AnchorSize.Limits = [10 200];
            app.Slid_AnchorSize.MajorTicks = [];
            app.Slid_AnchorSize.ValueChangedFcn = createCallbackFcn(app, @Slid_AnchorSizeValueChanged, true);
            app.Slid_AnchorSize.MinorTicks = [];
            app.Slid_AnchorSize.Value = 10;
            app.OpacityLabel = uilabel(app.AnchorTab);
            app.OpacityLabel.Text = 'Opacity:';
            app.Slid_AnchorAlpha = uislider(app.AnchorTab);
            app.Slid_AnchorAlpha.Limits = [10 90];
            app.Slid_AnchorAlpha.MajorTicks = [];
            app.Slid_AnchorAlpha.ValueChangedFcn = createCallbackFcn(app, @Slid_AnchorAlphaValueChanged, true);
            app.Slid_AnchorAlpha.MinorTicks = [];
            app.Slid_AnchorAlpha.Value = 10;
            app.Bn_DelAnchors = uibutton(app.AnchorTab, 'push');
            app.Bn_DelAnchors.ButtonPushedFcn = createCallbackFcn(app, @Bn_DelAnchorsButtonPushed, true);
            app.Bn_DelAnchors.Tooltip = {'Delete all anchors in this frame'};
            app.Bn_DelAnchors.Text = 'Delete all anchors';
            app.ContourvisibleSwitchLabel = uilabel(app.AnchorTab);
            app.ContourvisibleSwitchLabel.Text = 'Contour visible: ';
            app.Sw_ContourVisible = uiswitch(app.AnchorTab, 'slider');
            app.Sw_ContourVisible.ValueChangedFcn = createCallbackFcn(app, @Sw_ContourVisibleValueChanged, true);
            app.Sw_ContourVisible.Value = 'On';
            app.UIAnchorTable = uitable(app.AnchorTab);
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.UIAnchorTable.RowName = {};
            app.DeepEdgeTab = uitab(app.Tabs);
            app.DeepEdgeTab.Title = 'DeepEdge';
            app.DetectMethodGroup = uibuttongroup(app.DeepEdgeTab);
            app.DetectMethodGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectMethodGroupSelectionChanged, true);
            app.DetectMethodGroup.Title = 'Select algorithm:';
            app.DetectMethodGroup.Tag = 'DeteckMethodGroup';
            app.Ck_NN = uiradiobutton(app.DetectMethodGroup);
            app.Ck_NN.Text = 'NeuralNet';
            app.Ck_Snake = uiradiobutton(app.DetectMethodGroup);
            app.Ck_Snake.Text = 'SNAKE';
            app.Ck_NNSnake = uiradiobutton(app.DetectMethodGroup);
            app.Ck_NNSnake.Text = 'NN+SNAKE';
            app.Ck_NNSnake.Value = true;
            app.Bn_ApplyCurrentFrame = uibutton(app.DeepEdgeTab, 'push');
            app.Bn_ApplyCurrentFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Bn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Bn_TrackFrames = uibutton(app.DeepEdgeTab, 'state');
            app.Bn_TrackFrames.ValueChangedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Bn_TrackFrames.Tooltip = {'Tracking starts from the next frame to the last frame in the current file.'};
            app.Bn_TrackFrames.Text = 'Track thru Frames in this file';
            app.Bn_StopTrack = uibutton(app.DeepEdgeTab, 'state');
            app.Bn_StopTrack.ValueChangedFcn = createCallbackFcn(app, @StopTracking, true);
            app.Bn_StopTrack.Text = 'Stop Tracking';
            app.DelaymsecLabel = uilabel(app.DeepEdgeTab);
            app.DelaymsecLabel.Tooltip = {'Delay (10 ~ 5000 msec.) tracking between frames'};
            app.DelaymsecLabel.Text = 'Pause between frames (msec.)';
            app.Ed_TrackingDelayMs = uieditfield(app.DeepEdgeTab, 'numeric');
            app.Ed_TrackingDelayMs.Limits = [10 5000];
            app.Ed_TrackingDelayMs.ValueChangedFcn = createCallbackFcn(app, @Ed_TrackingDelayMsValueChanged, true);
            app.Ed_TrackingDelayMs.Tooltip = {'Pause (10 ~ 5000 msec.) between frames.'};
            app.Ed_TrackingDelayMs.Value = 20;
            app.Ck_UseMaskFromPreviousFrame = uicheckbox(app.DeepEdgeTab);
            app.Ck_UseMaskFromPreviousFrame.ValueChangedFcn = createCallbackFcn(app, @Ck_UseMaskFromPreviousFrameValueChanged, true);
            app.Ck_UseMaskFromPreviousFrame.Text = 'Use mask from previous frame';
            app.MasksigmaEditFieldLabel = uilabel(app.DeepEdgeTab);
            app.MasksigmaEditFieldLabel.Text = 'Mask sigma (%)';
            app.Ed_MaskSigma = uieditfield(app.DeepEdgeTab, 'numeric');
            app.Ed_MaskSigma.Limits = [0 50];
            app.Ed_MaskSigma.ValueDisplayFormat = '%2.0g';
            app.Ed_MaskSigma.ValueChangedFcn = createCallbackFcn(app, @Ed_MaskSigmaValueChanged, true);
            app.Ed_MaskSigma.Value = 0.7;
            app.Bn_TrackFiles = uibutton(app.DeepEdgeTab, 'state');
            app.Bn_TrackFiles.ValueChangedFcn = createCallbackFcn(app, @Bn_TrackFilesValueChanged, true);
            app.Bn_TrackFiles.Tooltip = {'Tracking starts from the next frame to the last frame in the current file.'};
            app.Bn_TrackFiles.Text = 'Track thru Files in list (auto-save on)';
            app.DiagnosticTab = uitab(app.Tabs);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.Pn_SnakeEngergy = uipanel(app.DiagnosticTab);
            app.Pn_SnakeEngergy.Title = 'SNAKE energy of this frame:';
            app.JointenergyLabel = uilabel(app.Pn_SnakeEngergy);
            app.JointenergyLabel.Text = 'Joint energy:';
            app.MeanEditFieldLabel = uilabel(app.Pn_SnakeEngergy);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.Ed_SnakeEnergyMean = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyMean.Editable = 'off';
            app.MaxEditFieldLabel = uilabel(app.Pn_SnakeEngergy);
            app.MaxEditFieldLabel.Text = 'Max';
            app.Ed_SnakeEnergyMax = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyMax.Editable = 'off';
            app.Q3Label = uilabel(app.Pn_SnakeEngergy);
            app.Q3Label.Text = 'Q3';
            app.Ed_SnakeEnergyQ3 = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyQ3.Editable = 'off';
            app.SmoothnessLabel = uilabel(app.Pn_SnakeEngergy);
            app.SmoothnessLabel.Text = 'Internal energy: roughness';
            app.Ed_Roughness = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_Roughness.Editable = 'off';
            app.Pn_DiagnoseFilter = uipanel(app.DiagnosticTab);
            app.Pn_DiagnoseFilter.Title = 'Diagnostic filters:';
            app.JointEnergyLabel = uilabel(app.Pn_DiagnoseFilter);
            app.JointEnergyLabel.Text = 'Joint Energy:';
            app.Ck_EnergyMeanFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyMeanFilter.Text = 'Mean > ';
            app.Ed_EnergyMeanFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyMeanFilter.Limits = [0 2];
            app.Ed_EnergyMeanFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyMeanFilter.Value = 0.35;
            app.Ck_EnergyMaxFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyMaxFilter.Text = 'Max > ';
            app.Ed_EnergyMaxFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyMaxFilter.Limits = [0 2];
            app.Ed_EnergyMaxFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyMaxFilter.Value = 0.8;
            app.Ck_EnergyQ3Filter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyQ3Filter.Text = 'Q3 > ';
            app.Ed_EnergyQ3Filter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyQ3Filter.Limits = [0 2];
            app.Ed_EnergyQ3Filter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyQ3Filter.Value = 0.7;
            app.RoughnessLabel = uilabel(app.Pn_DiagnoseFilter);
            app.RoughnessLabel.Text = 'Roughness:';
            app.Ck_RoughnessFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_RoughnessFilter.Text = '>';
            app.Ck_RoughnessFilter.Value = true;
            app.Ed_RoughnessFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_RoughnessFilter.Limits = [0 1];
            app.Ed_RoughnessFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_RoughnessFilter.Value = 0.05;
            app.Bn_Diagnose = uibutton(app.Pn_DiagnoseFilter, 'push');
            app.Bn_Diagnose.ButtonPushedFcn = createCallbackFcn(app, @Bn_DiagnoseButtonPushed, true);
            app.Bn_Diagnose.Text = 'Run Diagnostics';
            app.UIBadContourTable = uitable(app.DiagnosticTab);
            app.UIBadContourTable.ColumnName = {'Filtered Frame'};
            app.UIBadContourTable.CellSelectionCallback = createCallbackFcn(app, @UIBadContourTableCellSelection, true);
            app.UIBadContourTable.Multiselect = 'off';
            app.Ck_ShowDiagOnAudio = uicheckbox(app.DiagnosticTab);
            app.Ck_ShowDiagOnAudio.ValueChangedFcn = createCallbackFcn(app, @Ck_ShowDiagOnAudioValueChanged, true);
            app.Ck_ShowDiagOnAudio.Text = 'Show on audio';
            app.Ck_ShowDiagOnAudio.Value = true;
            app.Msg1 = uitextarea(app.UIFigure);
            app.Msg1.Editable = 'off';
            app.Bn_PreviousFile = uibutton(app.UIFigure, 'push');
            app.Bn_PreviousFile.ButtonPushedFcn = createCallbackFcn(app, @Bn_BrowseFileButtonPushed, true);
            app.Bn_PreviousFile.Tooltip = {'Previous File'};
            app.Bn_PreviousFile.Text = '< File';
            app.Pn_FrameControl = uipanel(app.UIFigure);
            app.Bn_FirstFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_FirstFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_FirstFrame.Tooltip = {'First frame'};
            app.Bn_FirstFrame.Text = '|<';
            app.Ed_PreviousN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_PreviousN.Limits = [1 Inf];
            app.Ed_PreviousN.ValueDisplayFormat = '%.0f';
            app.Ed_PreviousN.ValueChangedFcn = createCallbackFcn(app, @Ed_PreviousNValueChanged, true);
            app.Ed_PreviousN.Tooltip = {'Jump to previous N frame'};
            app.Ed_PreviousN.Value = 3;
            app.Bn_PreviousNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousNFrame.Tooltip = {'Jump to previous N frame'};
            app.Bn_PreviousNFrame.Text = '<<';
            app.Bn_PreviousFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousFrame.Tooltip = {'Previous frame'};
            app.Bn_PreviousFrame.Text = '<';
            app.Bn_NextFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextFrame.Tooltip = {'Next Frame'};
            app.Bn_NextFrame.Text = '>';
            app.Bn_NextNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextNFrame.Tooltip = {'Jump to next N frame'};
            app.Bn_NextNFrame.Text = '>>';
            app.Ed_NextN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_NextN.Limits = [1 Inf];
            app.Ed_NextN.ValueDisplayFormat = '%.0f';
            app.Ed_NextN.ValueChangedFcn = createCallbackFcn(app, @Ed_NextNValueChanged, true);
            app.Ed_NextN.Tooltip = {'Jump to next N frame'};
            app.Ed_NextN.Value = 3;
            app.Bn_LastFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_LastFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_LastFrame.Tooltip = {'Last frame'};
            app.Bn_LastFrame.Text = '>|';
            app.Bn_GoTo = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_GoTo.ButtonPushedFcn = createCallbackFcn(app, @Bn_GoToButtonPushed, true);
            app.Bn_GoTo.Tooltip = {'Jump to the Nth frame'};
            app.Bn_GoTo.Text = 'Go to';
            app.Ed_GoToN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_GoToN.Limits = [1 Inf];
            app.Ed_GoToN.ValueDisplayFormat = '%d';
            app.Ed_GoToN.ValueChangedFcn = createCallbackFcn(app, @Ed_GoToNValueChanged, true);
            app.Ed_GoToN.Tooltip = {'Jump to the Nth frame'};
            app.Ed_GoToN.Value = 1;
            app.Bn_NextFile = uibutton(app.UIFigure, 'push');
            app.Bn_NextFile.ButtonPushedFcn = createCallbackFcn(app, @Bn_BrowseFileButtonPushed, true);
            app.Bn_NextFile.Tooltip = {'Next File'};
            app.Bn_NextFile.Text = 'File >';
            app.Ed_CursorLoc = uieditfield(app.UIFigure, 'text');
            app.Ed_CursorLoc.Editable = 'off';
            app.Ed_CursorLoc.Enable = 'off';
            app.Ed_CursorLoc.Value = '0,0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepEdge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end